classdef MNPBEM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLmap                           matlab.ui.Figure
        QuickLoadMenu                   matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        QuickSaveMenu                   matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        Menu_4                          matlab.ui.container.Menu
        SaveresultsMenu                 matlab.ui.container.Menu
        RunButton                       matlab.ui.control.Button
        SolveroptionsPanel              matlab.ui.container.Panel
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        CalculationTogglePanel          matlab.ui.container.Panel
        TabGroup6                       matlab.ui.container.TabGroup
        CommonTab                       matlab.ui.container.Tab
        ChargemapsCheckBox              matlab.ui.control.CheckBox
        FieldmapsCheckBox               matlab.ui.control.CheckBox
        RadiativepatternsCheckBox       matlab.ui.control.CheckBox
        FarfieldspectrascatteringCheckBox  matlab.ui.control.CheckBox
        EmissionanglefarfieldscanCheckBox  matlab.ui.control.CheckBox
        EmissionangleellipticityscanCheckBox  matlab.ui.control.CheckBox
        TiltscanCheckBox                matlab.ui.control.CheckBox
        UserdefinedCheckBox             matlab.ui.control.CheckBox
        EBeamTab                        matlab.ui.container.Tab
        CathodoluminescencemapsCheckBox  matlab.ui.control.CheckBox
        EELSmapsCheckBox                matlab.ui.control.CheckBox
        EllipticitymapsCheckBox         matlab.ui.control.CheckBox
        PWTab                           matlab.ui.container.Tab
        PolarizationrotationCheckBox    matlab.ui.control.CheckBox
        DipoleTab_2                     matlab.ui.container.Tab
        DecayrateCheckBox               matlab.ui.control.CheckBox
        LDOS1DCheckBox                  matlab.ui.control.CheckBox
        LDOS3DCheckBox                  matlab.ui.control.CheckBox
        DecaymapsCheckBox               matlab.ui.control.CheckBox
        LumenhancementCheckBox          matlab.ui.control.CheckBox
        LumenhancementmapsCheckBox      matlab.ui.control.CheckBox
        LuminescencemapsCheckBox        matlab.ui.control.CheckBox
        EllipticitymapsCheckBox_2       matlab.ui.control.CheckBox
        StructureoptionsPanel           matlab.ui.container.Panel
        StructureDropDownLabel          matlab.ui.control.Label
        StructureDropDown               matlab.ui.control.DropDown
        DiameterLengthnmEditFieldLabel  matlab.ui.control.Label
        DiameterLengthnmEditField       matlab.ui.control.NumericEditField
        HeightnmEditFieldLabel          matlab.ui.control.Label
        HeightnmEditField               matlab.ui.control.NumericEditField
        WidthnmEditField_2Label         matlab.ui.control.Label
        WidthnmEditField_2              matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        MeshTab                         matlab.ui.container.Tab
        MeshresolutionPanel             matlab.ui.container.Panel
        ntriangulationpointsEditFieldLabel  matlab.ui.control.Label
        ntriangulationpointsEditField   matlab.ui.control.NumericEditField
        nfacesedgeEditFieldLabel        matlab.ui.control.Label
        nfacesedgeEditField             matlab.ui.control.NumericEditField
        maxelementsizeEditFieldLabel    matlab.ui.control.Label
        maxelementsizeEditField         matlab.ui.control.NumericEditField
        integrationpointsEditFieldLabel  matlab.ui.control.Label
        integrationpointsEditField      matlab.ui.control.NumericEditField
        diagonalintegrationpointsEditFieldLabel  matlab.ui.control.Label
        diagonalintegrationpointsEditField  matlab.ui.control.NumericEditField
        rangeofintergrationfacesEditFieldLabel  matlab.ui.control.Label
        rangeofintergrationfacesEditField  matlab.ui.control.NumericEditField
        SubstrateTab                    matlab.ui.container.Tab
        SubstrateoptionsPanel           matlab.ui.container.Panel
        SubstratelengthnmEditFieldLabel  matlab.ui.control.Label
        SubstratelengthnmEditField      matlab.ui.control.NumericEditField
        SubstratethicknessnmEditFieldLabel  matlab.ui.control.Label
        SubstratethicknessnmEditField   matlab.ui.control.NumericEditField
        SubstrateCheckBox               matlab.ui.control.CheckBox
        Substrate2CheckBox              matlab.ui.control.CheckBox
        Substrate2thicknessnmEditFieldLabel  matlab.ui.control.Label
        Substrate2thicknessnmEditField  matlab.ui.control.NumericEditField
        ExtraTab                        matlab.ui.container.Tab
        AdditionaloptionsPanel          matlab.ui.container.Panel
        DoubleStructureCheckBox         matlab.ui.control.CheckBox
        CoverlayerCheckBox              matlab.ui.control.CheckBox
        ThicknessnmEditFieldLabel       matlab.ui.control.Label
        ThicknessnmEditField            matlab.ui.control.NumericEditField
        ShiftnmEditFieldLabel           matlab.ui.control.Label
        ShiftnmEditField                matlab.ui.control.NumericEditField
        PolygonnEditFieldLabel          matlab.ui.control.Label
        PolygonnEditField               matlab.ui.control.NumericEditField
        TiltTab                         matlab.ui.container.Tab
        TiltangleDegreesPanel           matlab.ui.container.Panel
        aroundxEditFieldLabel           matlab.ui.control.Label
        aroundxEditField                matlab.ui.control.NumericEditField
        aroundyEditFieldLabel           matlab.ui.control.Label
        aroundyEditField                matlab.ui.control.NumericEditField
        aroundzEditFieldLabel           matlab.ui.control.Label
        aroundzEditField                matlab.ui.control.NumericEditField
        PermittivityoptionsPanel        matlab.ui.container.Panel
        TabGroup4                       matlab.ui.container.TabGroup
        TabGroup5                       matlab.ui.container.TabGroup
        StructureTab                    matlab.ui.container.Tab
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        ofenvironmentEditFieldLabel     matlab.ui.control.Label
        ofenvironmentEditField          matlab.ui.control.NumericEditField
        ofstructure2EditFieldLabel      matlab.ui.control.Label
        ofstructure2EditField           matlab.ui.control.NumericEditField
        orDropDownLabel                 matlab.ui.control.Label
        orDropDown                      matlab.ui.control.DropDown
        ofstructure1EditFieldLabel      matlab.ui.control.Label
        ofstructure1EditField           matlab.ui.control.NumericEditField
        orDropDown_2Label               matlab.ui.control.Label
        orDropDown_2                    matlab.ui.control.DropDown
        ofstructure3EditFieldLabel      matlab.ui.control.Label
        ofstructure3EditField           matlab.ui.control.NumericEditField
        orDropDown_3Label               matlab.ui.control.Label
        orDropDown_3                    matlab.ui.control.DropDown
        GrapheneMoS2Tab                 matlab.ui.container.Tab
        FermiLeveleVEditFieldLabel      matlab.ui.control.Label
        FermiLeveleVEditField           matlab.ui.control.NumericEditField
        Mobilitycm2VsEditFieldLabel     matlab.ui.control.Label
        Mobilitycm2VsEditField          matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel      matlab.ui.control.Label
        TemperatureKEditField           matlab.ui.control.NumericEditField
        EnablelossesCheckBox            matlab.ui.control.CheckBox
        DrudeTab                        matlab.ui.container.Tab
        PlasmafrequencyeVEditFieldLabel  matlab.ui.control.Label
        PlasmafrequencyeVEditField      matlab.ui.control.NumericEditField
        EpsiloninfinityEditFieldLabel   matlab.ui.control.Label
        EpsiloninfinityEditField        matlab.ui.control.NumericEditField
        eVEditFieldLabel                matlab.ui.control.Label
        eVEditField                     matlab.ui.control.NumericEditField
        LorentzTab                      matlab.ui.container.Tab
        peVEditFieldLabel               matlab.ui.control.Label
        peVEditField                    matlab.ui.control.NumericEditField
        egeVEditFieldLabel              matlab.ui.control.Label
        egeVEditField                   matlab.ui.control.NumericEditField
        egeVEditField_2Label            matlab.ui.control.Label
        egeVEditField_2                 matlab.ui.control.NumericEditField
        SourceandDetectorInputPanel     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        EnergyrangeTab                  matlab.ui.container.Tab
        EnergyRangeeVPanel              matlab.ui.container.Panel
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        StepsEditField_2Label           matlab.ui.control.Label
        StepsEditField_2                matlab.ui.control.NumericEditField
        DetectorTab                     matlab.ui.container.Tab
        DetectoranglePanel              matlab.ui.container.Panel
        XYplaneEditFieldLabel           matlab.ui.control.Label
        XYplaneEditField                matlab.ui.control.NumericEditField
        XZplaneEditFieldLabel           matlab.ui.control.Label
        XZplaneEditField                matlab.ui.control.NumericEditField
        DetectorrangePanel              matlab.ui.container.Panel
        RangeLabel                      matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RadiusnmEditFieldLabel          matlab.ui.control.Label
        RadiusnmEditField               matlab.ui.control.NumericEditField
        MeshEditFieldLabel              matlab.ui.control.Label
        MeshEditField                   matlab.ui.control.NumericEditField
        TextArea_6                      matlab.ui.control.TextArea
        Mapspatialrange0isautoPanel     matlab.ui.container.Panel
        XYnm2EditFieldLabel             matlab.ui.control.Label
        XYnm2EditField                  matlab.ui.control.NumericEditField
        ColormaprangeforField0valueforbothisautoPanel  matlab.ui.container.Panel
        startvalueEditFieldLabel        matlab.ui.control.Label
        startvalueEditField             matlab.ui.control.NumericEditField
        endvalueEditFieldLabel          matlab.ui.control.Label
        endvalueEditField               matlab.ui.control.NumericEditField
        BeamTab                         matlab.ui.container.Tab
        BeamoptionsPanel                matlab.ui.container.Panel
        KineticenergyeVEditFieldLabel   matlab.ui.control.Label
        KineticenergyeVEditField        matlab.ui.control.NumericEditField
        WidthnmEditFieldLabel           matlab.ui.control.Label
        WidthnmEditField                matlab.ui.control.NumericEditField
        BeamrangeforXdirectionFarfieldscannmPanel  matlab.ui.container.Panel
        MaxXEditFieldLabel              matlab.ui.control.Label
        MaxXEditField                   matlab.ui.control.NumericEditField
        StepsXEditFieldLabel            matlab.ui.control.Label
        StepsXEditField                 matlab.ui.control.NumericEditField
        MinXEditFieldLabel              matlab.ui.control.Label
        MinXEditField                   matlab.ui.control.NumericEditField
        TextArea_5                      matlab.ui.control.TextArea
        BeampositionnmPanel             matlab.ui.container.Panel
        PositionXEditFieldLabel         matlab.ui.control.Label
        PositionXEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ResolutionformapsPanel          matlab.ui.container.Panel
        StepsXYEditFieldLabel           matlab.ui.control.Label
        StepsXYEditField                matlab.ui.control.NumericEditField
        PWTab_2                         matlab.ui.container.Tab
        LightpolarizationPanel          matlab.ui.container.Panel
        PolarizationDropDownLabel       matlab.ui.control.Label
        PolarizationDropDown            matlab.ui.control.DropDown
        PolarizationangledegEditFieldLabel  matlab.ui.control.Label
        PolarizationangledegEditField   matlab.ui.control.NumericEditField
        JonesvectorsPanel               matlab.ui.container.Panel
        Dir_xEditFieldLabel             matlab.ui.control.Label
        Dir_xEditField                  matlab.ui.control.NumericEditField
        Dir_yEditFieldLabel             matlab.ui.control.Label
        Dir_yEditField                  matlab.ui.control.NumericEditField
        Dir_zEditFieldLabel             matlab.ui.control.Label
        Dir_zEditField                  matlab.ui.control.NumericEditField
        DirectionLabel                  matlab.ui.control.Label
        ExEditFieldLabel                matlab.ui.control.Label
        ExEditField                     matlab.ui.control.EditField
        EyEditFieldLabel                matlab.ui.control.Label
        EyEditField                     matlab.ui.control.EditField
        EzEditFieldLabel                matlab.ui.control.Label
        EzEditField                     matlab.ui.control.EditField
        DipoleTab                       matlab.ui.container.Tab
        DipolesettingsPanel             matlab.ui.container.Panel
        OscillationdirectionDropDownLabel  matlab.ui.control.Label
        OscillationdirectionDropDown    matlab.ui.control.DropDown
        DipolepositionnmPanel           matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        ReplacedipolewithQDCheckBox     matlab.ui.control.CheckBox
        TextArea_4                      matlab.ui.control.TextArea
        CancelButton                    matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        MNPBEMGUIV23Label               matlab.ui.control.Label
        StructurePanel                  matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        DviewButton                     matlab.ui.control.Button
        DetectorButton                  matlab.ui.control.Button
        RefractiveIndexPanel            matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        nk1Tab                          matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        nk2Tab                          matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        nk3Tab                          matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        ExcitationsourcePanel           matlab.ui.container.Panel
        SourceDropDownLabel             matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        TextArea_3                      matlab.ui.control.TextArea
        GUIauthorNMatthaiakakisLabel    matlab.ui.control.Label
    end


    properties (Access = public)
       cancelFlag 
       saveFlag
    end

    methods (Access = private)    
        function callerAll (app, event) % Refreshes GUI values and tmp files
            SolverDropDownValueChanged(app, event)
            SourceDropDownValueChanged(app, event)
            PolarizationDropDownValueChanged(app, event)
            OscillationdirectionDropDownValueChanged(app, event)
            FarfieldspectrascatteringCheckBoxValueChanged(app, event)
            ChargemapsCheckBoxValueChanged(app, event)
            ExEditFieldValueChanged(app, event)
            EyEditFieldValueChanged(app, event)
            EzEditFieldValueChanged(app, event)
            RadiativepatternsCheckBoxValueChanged(app, event)
            CathodoluminescencemapsCheckBoxValueChanged(app, event)
            TiltscanCheckBoxValueChanged(app, event)
            UserdefinedCheckBoxValueChanged(app, event)
            LuminescencemapsCheckBoxValueChanged(app, event)
            EllipticitymapsCheckBox_2ValueChanged(app, event)
            LumenhancementCheckBoxValueChanged(app, event)
            LumenhancementmapsCheckBoxValueChanged(app, event)
            ReplacedipolewithQDCheckBoxValueChanged(app, event)
            PolarizationrotationCheckBoxValueChanged(app, event)
            DecayrateCheckBoxValueChanged(app, event)
            LDOS1DCheckBoxValueChanged(app, event)
            LDOS3DCheckBoxValueChanged(app, event)
            DecaymapsCheckBoxValueChanged(app, event)
            FieldmapsCheckBoxValueChanged(app, event)
            EELSmapsCheckBoxValueChanged(app, event) 
            Dir_xEditFieldValueChanged(app, event)
            Dir_yEditFieldValueChanged(app, event)
            Dir_zEditFieldValueChanged(app, event)
            aroundxEditFieldValueChanged(app, event)
            aroundyEditFieldValueChanged(app, event)
            aroundzEditFieldValueChanged(app, event)
            DiameterLengthnmEditFieldValueChanged(app, event)
            HeightnmEditFieldValueChanged(app, event)
            WidthnmEditField_2ValueChanged(app, event)
            CoverlayerCheckBoxValueChanged(app, event)
            ThicknessnmEditFieldValueChanged(app, event)
            DoubleStructureCheckBoxValueChanged(app, event)
            ShiftnmEditFieldValueChanged(app, event)
            SubstratelengthnmEditFieldValueChanged(app, event)
            integrationpointsEditFieldValueChanged3(app, event)
            diagonalintegrationpointsEditFieldValueChanged2(app, event)
            rangeofintergrationfacesEditFieldValueChanged(app, event)
            ofenvironmentEditFieldValueChanged(app, event)
            orDropDownValueChanged(app, event)
            orDropDown_2ValueChanged(app, event)
            orDropDown_3ValueChanged(app, event)
            ofstructure1EditFieldValueChanged(app, event)
            ofstructure2EditFieldValueChanged(app, event)
            ofstructure3EditFieldValueChanged(app, event)
            MinEditField_2ValueChanged(app, event)
            MaxEditField_2ValueChanged(app, event)
            StepsEditField_2ValueChanged(app, event)
            KineticenergyeVEditFieldValueChanged(app, event)
            WidthnmEditFieldValueChanged(app, event)
            MinXEditFieldValueChanged(app, event)
            MaxXEditFieldValueChanged(app, event)
            StepsXEditFieldValueChanged(app, event)
            PositionXEditFieldValueChanged(app, event)
            YEditFieldValueChanged(app, event)
            XYnm2EditFieldValueChanged(app, event)
            startvalueEditFieldValueChanged(app, event)
            endvalueEditFieldValueChanged(app, event)
            XYplaneEditFieldValueChanged(app, event)
            XZplaneEditFieldValueChanged(app, event)
            RangeEditFieldValueChanged(app, event)
            PolarizationangledegEditFieldValueChanged(app, event)
            StepsXYEditFieldValueChanged(app, event)
            XYnm2EditFieldValueChanged(app, event)
            startvalueEditFieldValueChanged(app, event)
            endvalueEditFieldValueChanged(app, event)
            RadiusnmEditFieldValueChanged(app, event)
            MeshEditFieldValueChanged(app, event)
            XEditFieldValueChanged(app, event)
            YEditField_2ValueChanged(app, event)
            ZEditField_2ValueChanged(app, event)
            FermiLeveleVEditFieldValueChanged(app, event)
            Mobilitycm2VsEditFieldValueChanged(app, event)
            TemperatureKEditFieldValueChanged(app, event)
            PlasmafrequencyeVEditFieldValueChanged(app, event)
            EpsiloninfinityEditFieldValueChanged(app, event)
            eVEditFieldValueChanged4(app, event)
            peVEditFieldValueChanged(app, event)
            egeVEditFieldValueChanged(app, event)
            egeVEditField_2ValueChanged(app, event)
            EmissionanglefarfieldscanCheckBoxValueChanged(app, event)
            EllipticitymapsCheckBoxValueChanged(app, event)
            EmissionangleellipticityscanCheckBoxValueChanged(app, event)
            EnablelossesCheckBoxValueChanged(app, event)
            PolygonnEditFieldValueChanged(app, event)
            maxelementsizeEditFieldValueChanged(app, event)
            StructureDropDownValueChanged(app, event)
            ntriangulationpointsEditFieldValueChanged(app, event)
            nfacesedgeEditFieldValueChanged(app, event)
            SubstrateCheckBoxValueChanged(app, event)
            SubstratethicknessnmEditFieldValueChanged(app, event)
            CheckBox_2ValueChanged(app, event)
            CheckBoxValueChanged(app, event)
            CheckBox_3ValueChanged(app, event)
            Substrate2CheckBoxValueChanged(app, event)
            Substrate2thicknessnmEditFieldValueChanged(app, event)
        end
        
        function callerStruct(app, event) % Refreshes GUI values and tmp files for structure preview
            SolverDropDownValueChanged(app, event)      
            SourceDropDownValueChanged(app, event)
            PolarizationDropDownValueChanged(app, event)
            OscillationdirectionDropDownValueChanged(app, event)
            ofenvironmentEditFieldValueChanged(app, event)
            orDropDownValueChanged(app, event)
            orDropDown_2ValueChanged(app, event)
            ofstructure2EditFieldValueChanged(app, event)   
            aroundxEditFieldValueChanged(app, event)
            aroundyEditFieldValueChanged(app, event)
            aroundzEditFieldValueChanged(app, event)
            DiameterLengthnmEditFieldValueChanged(app, event)
            HeightnmEditFieldValueChanged(app, event)
            integrationpointsEditFieldValueChanged3(app, event)
            diagonalintegrationpointsEditFieldValueChanged2(app, event)
            rangeofintergrationfacesEditFieldValueChanged(app, event)
            WidthnmEditField_2ValueChanged(app, event)
            CoverlayerCheckBoxValueChanged(app, event)
            ThicknessnmEditFieldValueChanged(app, event)
            DoubleStructureCheckBoxValueChanged(app, event)
            ShiftnmEditFieldValueChanged(app, event)
            PolygonnEditFieldValueChanged(app, event)
            SubstratelengthnmEditFieldValueChanged(app, event)
            maxelementsizeEditFieldValueChanged(app, event)
            ntriangulationpointsEditFieldValueChanged(app, event)
            nfacesedgeEditFieldValueChanged(app, event)
            SubstrateCheckBoxValueChanged(app, event)
            SubstratethicknessnmEditFieldValueChanged(app, event)
            CheckBox_2ValueChanged(app, event)
        end
        
        function strGUIplot(app)
            strPlot();
            % Remove title, axis labels, and tick labels
            title(app.UIAxes2, []);
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};            
            % Display image and stretch to fill axes
            I=imshow('data/str_tmp.png','Parent',app.UIAxes2);                     
            % Set limits of axes
            app.UIAxes2.XLim = [0 I.XData(2)];
            app.UIAxes2.YLim = [0 I.YData(2)];    
            axis(app.UIAxes2, 'image') 
        end               
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Change the current folder to the folder of this m-file.
            if(~isdeployed)
                cd(fileparts(which('choose_dir.txt')));
            end
            addpath( genpath( '../MNPBEM_GUI' ) ); % Adds system path
            addpath( genpath( 'MNPBEM17' ) ); % Adds library path
            addpath( genpath( 'functions' ) ); % Adds functions path
            app.saveFlag=0;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Runs the code
            sprintf('Calculation began')
            cFlag = 0; % Set flag init            
            save('tmp/cFlag.mat','cFlag') % Initializes cancel flag tmp
            app.saveFlag=1;
            callerAll (app, event) % Refreshes all GUI values
            app.saveFlag=0;
            strGUIplot(app)
            CL_app() % Runs the main calculation m-file
            clear app.cancelFlag % Clears cancel flag            
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            BEM_op = str2double(app.SolverDropDown.Value);
            save('tmp/BEM_op.mat','BEM_op')
        end

        % Value changed function: FarfieldspectrascatteringCheckBox
        function FarfieldspectrascatteringCheckBoxValueChanged(app, event)
            EELS_CL = double(app.FarfieldspectrascatteringCheckBox.Value);
            save('tmp/EELS_CL.mat','EELS_CL')
        end

        % Value changed function: CathodoluminescencemapsCheckBox
        function CathodoluminescencemapsCheckBoxValueChanged(app, event)
            CL_map_en = double(app.CathodoluminescencemapsCheckBox.Value);
            save('tmp/CL_map_en.mat','CL_map_en')
        end

        % Value changed function: FieldmapsCheckBox
        function FieldmapsCheckBoxValueChanged(app, event)
            Field_en = double(app.FieldmapsCheckBox.Value);
            save('tmp/Field_en.mat','Field_en')
        end

        % Value changed function: EELSmapsCheckBox
        function EELSmapsCheckBoxValueChanged(app, event)
            EELS_en = double(app.EELSmapsCheckBox.Value);
            save('tmp/EELS_en.mat','EELS_en')
        end

        % Value changed function: StructureDropDown
        function StructureDropDownValueChanged(app, event)
            typeStr = str2double(app.StructureDropDown.Value);
            save('tmp/typeStr.mat','typeStr')  
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: DiameterLengthnmEditField
        function DiameterLengthnmEditFieldValueChanged(app, event)
            d_length = app.DiameterLengthnmEditField.Value;
            save('tmp/d_length.mat','d_length')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: HeightnmEditField
        function HeightnmEditFieldValueChanged(app, event)
            edge_profile = app.HeightnmEditField.Value;
            save('tmp/edge_profile.mat','edge_profile')            
            graphene_m( )
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: WidthnmEditField_2
        function WidthnmEditField_2ValueChanged(app, event)
            d_length2 = app.WidthnmEditField_2.Value;
            save('tmp/d_length2.mat','d_length2')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: CoverlayerCheckBox
        function CoverlayerCheckBoxValueChanged(app, event)
            Cover_en = double(app.CoverlayerCheckBox.Value);
            save('tmp/Cover_en.mat','Cover_en')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: ThicknessnmEditField
        function ThicknessnmEditFieldValueChanged(app, event)
            d_c = app.ThicknessnmEditField.Value;
            save('tmp/d_c.mat','d_c')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: DoubleStructureCheckBox
        function DoubleStructureCheckBoxValueChanged(app, event)
            Double_en = double(app.DoubleStructureCheckBox.Value);
            save('tmp/Double_en.mat','Double_en')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: ShiftnmEditField
        function ShiftnmEditFieldValueChanged(app, event)
            shift_l = app.ShiftnmEditField.Value;
            save('tmp/shift_l.mat','shift_l')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: SubstratelengthnmEditField
        function SubstratelengthnmEditFieldValueChanged(app, event)
            d_length3 = app.SubstratelengthnmEditField.Value;
            save('tmp/d_length3.mat','d_length3')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: ofenvironmentEditField
        function ofenvironmentEditFieldValueChanged(app, event)
            eps_env = app.ofenvironmentEditField.Value;
            save('tmp/eps_env.mat','eps_env')
        end

        % Value changed function: orDropDown
        function orDropDownValueChanged(app, event)
            eps_part = app.orDropDown.Value;
            save('tmp/eps_part.mat','eps_part')
            graphene_m( )
            mos2_m()
            Drude_m()
            Lorentz_m()
            [n_loc,k_loc, enei_loc,~,~, ~] = refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');
        end

        % Value changed function: ofstructure2EditField
        function ofstructure2EditFieldValueChanged(app, event)
            eps_p = app.ofstructure2EditField.Value;
            save('tmp/eps_p.mat','eps_p')             
        end

        % Value changed function: MinEditField_2
        function MinEditField_2ValueChanged(app, event)
            minene2 = app.MinEditField_2.Value;
            save('tmp/minene2.mat','minene2')   
            graphene_m( )
            mos2_m()
            Drude_m()
            Lorentz_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            maxene2 = app.MaxEditField_2.Value;
            save('tmp/maxene2.mat','maxene2')  
            graphene_m( )
            mos2_m()
            Drude_m()
            Lorentz_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: StepsEditField_2
        function StepsEditField_2ValueChanged(app, event)
            stepene2 = app.StepsEditField_2.Value;
            save('tmp/stepene2.mat','stepene2')   
            graphene_m( )
            mos2_m()
            Drude_m()
            Lorentz_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Callback function
        function EnergyEditField_2ValueChanged(app, event)
            ene3 = app.EnergyEditField_2.Value;
            save('tmp/ene3.mat','ene3')       
        end

        % Value changed function: KineticenergyeVEditField
        function KineticenergyeVEditFieldValueChanged(app, event)
            e_beam = app.KineticenergyeVEditField.Value;
            save('tmp/e_beam.mat','e_beam')              
        end

        % Value changed function: WidthnmEditField
        function WidthnmEditFieldValueChanged(app, event)
            width_d = app.WidthnmEditField.Value;
            save('tmp/width_d.mat','width_d')              
        end

        % Value changed function: MinXEditField
        function MinXEditFieldValueChanged(app, event)
            minimp = app.MinXEditField.Value;
            save('tmp/minimp.mat','minimp')    
        end

        % Value changed function: MaxXEditField
        function MaxXEditFieldValueChanged(app, event)
            maximp = app.MaxXEditField.Value;
            save('tmp/maximp.mat','maximp')              
        end

        % Value changed function: StepsXEditField
        function StepsXEditFieldValueChanged(app, event)
            stepimp = app.StepsXEditField.Value;
            save('tmp/stepimp.mat','stepimp')           
        end

        % Value changed function: PositionXEditField
        function PositionXEditFieldValueChanged(app, event)
            imp_specX= app.PositionXEditField.Value;
            save('tmp/imp_specX.mat','imp_specX')  
        end

        % Value changed function: XYplaneEditField
        function XYplaneEditFieldValueChanged(app, event)
            d_angle1a = app.XYplaneEditField.Value;
            save('tmp/d_angle1a.mat','d_angle1a')            
        end

        % Value changed function: XZplaneEditField
        function XZplaneEditFieldValueChanged(app, event)
            % Electrons propagate in the positive direction
            % Angular value set as theta=180-input for consistency with the experiment (in the main code)
            d_angle2a = app.XZplaneEditField.Value;
            save('tmp/d_angle2a.mat','d_angle2a')            
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            d_range1a = app.RangeEditField.Value;
            save('tmp/d_range1a.mat','d_range1a')             
        end

        % Value changed function: PolarizationangledegEditField
        function PolarizationangledegEditFieldValueChanged(app, event)
            d_range2a = app.PolarizationangledegEditField.Value;
            save('tmp/d_range2a.mat','d_range2a')
        end

        % Menu selected function: QuickLoadMenu
        function QuickLoadMenuSelected(app, event)
            % Loads saved files to the GUI and tmp folder                                    
            app.saveFlag=1;
            copyfile( 'save', 'tmp')             
            mat = dir('tmp/*.mat');
            for q = 1:length(mat) 
                load(mat(q).name); 
            end 
            app.aroundxEditField.Value = xtilt;
            app.aroundyEditField.Value = ytilt;
            app.aroundzEditField.Value = ztilt;
            app.DiameterLengthnmEditField.Value = d_length;            
            app.HeightnmEditField.Value = edge_profile;            
            app.WidthnmEditField_2.Value = d_length2;            
            app.ThicknessnmEditField.Value = d_c;            
            app.ShiftnmEditField.Value = shift_l;           
            app.SubstratelengthnmEditField.Value = d_length3;            
            app.ofenvironmentEditField.Value = eps_env;            
            app.ofstructure2EditField.Value = eps_p;  
            app.ofstructure3EditField.Value = eps_p2;
            app.orDropDown.Value = eps_part;
            app.orDropDown_2.Value=eps_part2;                                  
            app.MinEditField_2.Value = minene2;           
            app.MaxEditField_2.Value = maxene2;           
            app.StepsEditField_2.Value = stepene2; 
            app.integrationpointsEditField.Value = intPoint;
            app.diagonalintegrationpointsEditField.Value=dIntPoint;
            app.rangeofintergrationfacesEditField.Value=Relcut;
            app.KineticenergyeVEditField.Value = e_beam;           
            app.WidthnmEditField.Value = width_d;        
            app.MinXEditField.Value = minimp;            
            app.MaxXEditField.Value = maximp;           
            app.StepsXEditField.Value = stepimp;            
            app.PositionXEditField.Value = imp_specX;   
            app.YEditField.Value = imp_specY;
            app.XYnm2EditField.Value = XYField;
            app.startvalueEditField.Value = cmSt;
            app.endvalueEditField.Value = cmEn;
            app.RadiusnmEditField.Value = detRad;
            app.MeshEditField.Value = detQ;
            app.Dir_xEditField.Value=dir_x;
            app.Dir_yEditField.Value=dir_y;
            app.Dir_zEditField.Value=dir_z;
            app.XEditField.Value = dip_x;
            app.YEditField_2.Value = dip_y;
            app.ZEditField_2.Value = dip_z;
            app.FermiLeveleVEditField.Value = Ef_1;
            app.Mobilitycm2VsEditField.Value = mobil;
            app.TemperatureKEditField.Value = T;
            app.PlasmafrequencyeVEditField.Value =  omega_p;
            app.EpsiloninfinityEditField.Value = eps_inf;
            app.eVEditField.Value = gamaeV;
            app.peVEditField.Value = omega_pl;
            app.egeVEditField.Value = omega_eg;
            app.egeVEditField_2.Value = gama_eg;
            app.EmissionanglefarfieldscanCheckBox.Value = logical(CLang);
            app.EllipticitymapsCheckBox.Value = logical(Pol_map_en);
            app.EmissionangleellipticityscanCheckBox.Value = logical(Pol_ang_en);
            app.EnablelossesCheckBox.Value = logical(Drude);
            app.XYplaneEditField.Value = d_angle1a;            
            app.XZplaneEditField.Value = d_angle2a;            
            app.RangeEditField.Value = d_range1a;            
            app.PolarizationangledegEditField.Value = d_range2a;  
            app.StepsXYEditField.Value=res_map;
            app.PolygonnEditField.Value=angles_n;
            app.ntriangulationpointsEditField.Value=grid_1;
            app.nfacesedgeEditField.Value=grid_2;
            app.maxelementsizeEditField.Value=hdata; 
            app.SubstratethicknessnmEditField.Value=sub_t;
            app.Substrate2thicknessnmEditField.Value=sub_t2; 
            app.ofstructure1EditField.Value=eps_str;                        
            app.SolverDropDown.Value=num2str(BEM_op);
            app.SourceDropDown.Value=num2str(Source_op);
            app.ExEditField.Value=num2str(jones_x);
            app.EyEditField.Value=num2str(jones_y);
            app.EzEditField.Value=num2str(jones_z);
            app.PolarizationDropDown.Value = num2str(pw_pol);
            app.OscillationdirectionDropDown.Value = num2str(dip_d);
            app.FarfieldspectrascatteringCheckBox.Value=logical(EELS_CL);
            app.ChargemapsCheckBox.Value=logical(charge_f);
            app.RadiativepatternsCheckBox.Value=logical(rad_f);
            app.CathodoluminescencemapsCheckBox.Value=logical(CL_map_en);
            app.TiltscanCheckBox.Value=logical(tilt_en);
            app.UserdefinedCheckBox.Value=logical(user_en);
            app.LuminescencemapsCheckBox.Value=logical(Ld_map_en);
            app.EllipticitymapsCheckBox_2.Value=logical(Pold_map_en);
            app.LumenhancementCheckBox.Value=logical(Enh_sp_en);
            app.LumenhancementmapsCheckBox.Value=logical(Enh_map_en);
            app.ReplacedipolewithQDCheckBox.Value=logical(qd_en);
            app.PolarizationrotationCheckBox.Value=logical(pol_rot);
            app.DecayrateCheckBox.Value=logical(decay_en);
            app.LDOS1DCheckBox.Value=logical(LDOS_en);
            app.LDOS3DCheckBox.Value=logical(LDOS3D_en);
            app.DecaymapsCheckBox.Value=logical(Dmaps_en);
            app.FieldmapsCheckBox.Value=logical(Field_en);
            app.EELSmapsCheckBox.Value=logical(EELS_en);
            app.StructureDropDown.Value=num2str(typeStr);
            app.SubstrateCheckBox.Value=logical(substrate);
            app.CoverlayerCheckBox.Value=logical(Cover_en);
            app.DoubleStructureCheckBox.Value=logical(Double_en);
            app.CheckBox.Value=logical(eps_list_en);
            app.CheckBox_2.Value=logical(eps_list_en2);
            app.Substrate2CheckBox.Value=logical(substrate2);
            app.CheckBox_3.Value=logical(eps_list_en3);
            callerAll(app,event)
            app.saveFlag=0;
            strGUIplot(app)
            sprintf('Loading finished')
        end

        % Menu selected function: QuickSaveMenu
        function QuickSaveMenuSelected(app, event)
            app.saveFlag=1;
            callerAll(app,event)
            app.saveFlag=0;
            copyfile('tmp', 'save')
            sprintf('Saving finished')
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            cFlag=1; 
            save('tmp/cFlag.mat','cFlag')                                    
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
        doc
        end

        % Value changed function: ChargemapsCheckBox
        function ChargemapsCheckBoxValueChanged(app, event)
            charge_f = double(app.ChargemapsCheckBox.Value);
            save('tmp/charge_f.mat','charge_f') 
        end

        % Value changed function: RadiativepatternsCheckBox
        function RadiativepatternsCheckBoxValueChanged(app, event)
            rad_f = double(app.RadiativepatternsCheckBox.Value);
            save('tmp/rad_f.mat','rad_f') 
        end

        % Value changed function: StepsXYEditField
        function StepsXYEditFieldValueChanged(app, event)
            res_map = app.StepsXYEditField.Value;
            save('tmp/res_map.mat','res_map')
        end

        % Value changed function: PolygonnEditField
        function PolygonnEditFieldValueChanged(app, event)
            angles_n = app.PolygonnEditField.Value;
            save('tmp/angles_n.mat','angles_n')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: ntriangulationpointsEditField
        function ntriangulationpointsEditFieldValueChanged(app, event)
            grid_1 = app.ntriangulationpointsEditField.Value;
            save('tmp/grid_1.mat','grid_1')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: nfacesedgeEditField
        function nfacesedgeEditFieldValueChanged(app, event)
            grid_2 = app.nfacesedgeEditField.Value;
            save('tmp/grid_2.mat','grid_2')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: maxelementsizeEditField
        function maxelementsizeEditFieldValueChanged(app, event)
            hdata = app.maxelementsizeEditField.Value;
            save('tmp/hdata.mat','hdata')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: SubstrateCheckBox
        function SubstrateCheckBoxValueChanged(app, event)
            substrate = double(app.SubstrateCheckBox.Value);
            save('tmp/substrate.mat','substrate')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: SubstratethicknessnmEditField
        function SubstratethicknessnmEditFieldValueChanged(app, event)
            sub_t = app.SubstratethicknessnmEditField.Value;
            save('tmp/sub_t.mat','sub_t')
            if app.saveFlag~=1
                strGUIplot(app)
            end            
        end

        % Value changed function: orDropDown_2
        function orDropDown_2ValueChanged(app, event)
            eps_part2 = app.orDropDown_2.Value;
            save('tmp/eps_part2.mat','eps_part2')
            graphene_m( )
            mos2_m()
            Drude_m()
            Lorentz_m()
            [~,~, ~,n_loc2,k_loc2, enei_loc2] = refPlot();            
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            eps_list_en = double(app.CheckBox.Value);
            save('tmp/eps_list_en.mat','eps_list_en')
        end

        % Value changed function: ofstructure1EditField
        function ofstructure1EditFieldValueChanged(app, event)
            eps_str = app.ofstructure1EditField.Value;
            save('tmp/eps_str.mat','eps_str')
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            eps_list_en2 = double(app.CheckBox_2.Value);
            save('tmp/eps_list_en2.mat','eps_list_en2')
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.saveFlag=1;
            callerAll(app,event)
            app.saveFlag=0;
            mat = dir('tmp/*.mat'); 
            mat.name;
            for q = 1:length(mat) 
                load(['tmp/' sprintf( mat(q).name)]); 
            end                        
            [file,path] = uiputfile('*.mat');
            filename = fullfile(path,file);
            save(filename, '-regexp', '^(?!(event|app)$).')
            sprintf('Saving finished')
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % Loads saved files to the GUI and tmp folder
            mat = dir('tmp/*.mat');
            for q = 1:length(mat) 
                load(mat(q).name); 
            end 
            uiopen
            app.saveFlag=1;  
            app.aroundxEditField.Value = xtilt;
            app.aroundyEditField.Value = ytilt;
            app.aroundzEditField.Value = ztilt;
            app.DiameterLengthnmEditField.Value = d_length;            
            app.HeightnmEditField.Value = edge_profile;            
            app.WidthnmEditField_2.Value = d_length2;            
            app.ThicknessnmEditField.Value = d_c;            
            app.ShiftnmEditField.Value = shift_l;           
            app.SubstratelengthnmEditField.Value = d_length3;            
            app.ofenvironmentEditField.Value = eps_env;            
            app.ofstructure2EditField.Value = eps_p;   
            app.ofstructure3EditField.Value = eps_p2;
            app.orDropDown.Value = eps_part;           
            app.integrationpointsEditField.Value = intPoint;
            app.diagonalintegrationpointsEditField.Value=dIntPoint;
            app.rangeofintergrationfacesEditField.Value=Relcut;                         
            app.MinEditField_2.Value = minene2;           
            app.MaxEditField_2.Value = maxene2;           
            app.StepsEditField_2.Value = stepene2;                              
            app.KineticenergyeVEditField.Value = e_beam;           
            app.WidthnmEditField.Value = width_d;        
            app.MinXEditField.Value = minimp;            
            app.MaxXEditField.Value = maximp;           
            app.StepsXEditField.Value = stepimp;            
            app.PositionXEditField.Value = imp_specX;   
            app.YEditField.Value = imp_specY;
            app.XYnm2EditField.Value = XYField;
            app.startvalueEditField.Value = cmSt;
            app.endvalueEditField.Value = cmEn;
            app.RadiusnmEditField.Value = detRad;
            app.MeshEditField.Value = detQ;
            app.ExEditField.Value=num2str(jones_x);
            app.EyEditField.Value=num2str(jones_y);
            app.EzEditField.Value=num2str(jones_z);
            app.Dir_xEditField.Value=dir_x;
            app.Dir_yEditField.Value=dir_y;
            app.Dir_zEditField.Value=dir_z;
            app.XEditField.Value = dip_x;
            app.YEditField_2.Value = dip_y;
            app.ZEditField_2.Value = dip_z;
            app.FermiLeveleVEditField.Value = Ef_1;
            app.Mobilitycm2VsEditField.Value = mobil;
            app.TemperatureKEditField.Value = T;
            app.PlasmafrequencyeVEditField.Value =  omega_p;
            app.EpsiloninfinityEditField.Value = eps_inf;
            app.eVEditField.Value = gamaeV;
            app.peVEditField.Value = omega_pl;
            app.egeVEditField.Value = omega_eg;
            app.egeVEditField_2.Value = gama_eg;
            app.EmissionanglefarfieldscanCheckBox.Value = logical(CLang);
            app.EllipticitymapsCheckBox.Value = logical(Pol_map_en);
            app.EmissionangleellipticityscanCheckBox.Value = logical(Pol_ang_en);
            app.EnablelossesCheckBox.Value = logical(Drude);
            app.XYplaneEditField.Value = d_angle1a;            
            app.XZplaneEditField.Value = d_angle2a;            
            app.RangeEditField.Value = d_range1a;            
            app.PolarizationangledegEditField.Value = d_range2a;  
            app.StepsXYEditField.Value=res_map;
            app.PolygonnEditField.Value=angles_n;
            app.ntriangulationpointsEditField.Value=grid_1;
            app.nfacesedgeEditField.Value=grid_2;
            app.maxelementsizeEditField.Value=hdata; 
            app.SubstratethicknessnmEditField.Value=sub_t;
            app.Substrate2thicknessnmEditField.Value=sub_t2; 
            app.ofstructure1EditField.Value=eps_str;
            app.orDropDown_2.Value=eps_part2; 
            app.orDropDown_3.Value=eps_part3; 
            app.SolverDropDown.Value=num2str(BEM_op);
            app.SourceDropDown.Value=num2str(Source_op);
            app.PolarizationDropDown.Value = num2str(pw_pol);
            app.OscillationdirectionDropDown.Value = num2str(dip_d);
            app.FarfieldspectrascatteringCheckBox.Value=logical(EELS_CL);
            app.ChargemapsCheckBox.Value=logical(charge_f);
            app.RadiativepatternsCheckBox.Value=logical(rad_f);
            app.CathodoluminescencemapsCheckBox.Value=logical(CL_map_en);
            app.TiltscanCheckBox.Value=logical(tilt_en);
            app.UserdefinedCheckBox.Value=logical(user_en);
            app.LuminescencemapsCheckBox.Value=logical(Ld_map_en);
            app.EllipticitymapsCheckBox_2.Value=logical(Pold_map_en);
            app.LumenhancementCheckBox.Value=logical(Enh_sp_en);
            app.LumenhancementmapsCheckBox.Value=logical(Enh_map_en);
            app.ReplacedipolewithQDCheckBox.Value=logical(qd_en);
            app.PolarizationrotationCheckBox.Value=logical(pol_rot);
            app.DecayrateCheckBox.Value=logical(decay_en);
            app.LDOS1DCheckBox.Value=logical(LDOS_en);
            app.LDOS3DCheckBox.Value=logical(LDOS3D_en);
            app.DecaymapsCheckBox.Value=logical(Dmaps_en);
            app.FieldmapsCheckBox.Value=logical(Field_en);
            app.EELSmapsCheckBox.Value=logical(EELS_en);
            app.StructureDropDown.Value=num2str(typeStr);
            app.SubstrateCheckBox.Value=logical(substrate);
            app.CoverlayerCheckBox.Value=logical(Cover_en);
            app.DoubleStructureCheckBox.Value=logical(Double_en);
            app.CheckBox.Value=logical(eps_list_en);
            app.CheckBox_2.Value=logical(eps_list_en2);
            app.Substrate2CheckBox.Value=logical(substrate2);
            app.CheckBox_3.Value=logical(eps_list_en3);
            callerAll(app,event)
            app.saveFlag=0;
            strGUIplot(app)
            sprintf('Loading finished')
        end

        % Menu selected function: SaveresultsMenu
        function SaveresultsMenuSelected(app, event)
            path = uigetdir;            
            copyfile( 'data', path)             
        end

        % Value changed function: Substrate2CheckBox
        function Substrate2CheckBoxValueChanged(app, event)
            substrate2 = double(app.Substrate2CheckBox.Value);
            save('tmp/substrate2.mat','substrate2')
            if app.saveFlag~=1
                strGUIplot(app)
            end
            
        end

        % Value changed function: Substrate2thicknessnmEditField
        function Substrate2thicknessnmEditFieldValueChanged(app, event)
            sub_t2 = app.Substrate2thicknessnmEditField.Value;
            save('tmp/sub_t2.mat','sub_t2')
            if app.saveFlag~=1
                strGUIplot(app)
            end    
        end

        % Value changed function: ofstructure3EditField
        function ofstructure3EditFieldValueChanged(app, event)
            eps_p2  = app.ofstructure3EditField.Value;
            save('tmp/eps_p2.mat','eps_p2')  
        end

        % Value changed function: orDropDown_3
        function orDropDown_3ValueChanged(app, event)
            eps_part3 = app.orDropDown_3.Value;
            save('tmp/eps_part3.mat','eps_part3')
            graphene_m( )
            mos2_m()
            Drude_m()
            Lorentz_m()
            [~,~, ~,~,~, ~,n_loc3,k_loc3, enei_loc3] = refPlot();            
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            eps_list_en3 = double(app.CheckBox_3.Value);
            save('tmp/eps_list_en3.mat','eps_list_en3')
        end

        % Value changed function: integrationpointsEditField
        function integrationpointsEditFieldValueChanged3(app, event)
            intPoint = app.integrationpointsEditField.Value;
            save('tmp/intPoint.mat','intPoint')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: diagonalintegrationpointsEditField
        function diagonalintegrationpointsEditFieldValueChanged2(app, event)
            dIntPoint = app.diagonalintegrationpointsEditField.Value;
            save('tmp/dIntPoint.mat','dIntPoint')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: rangeofintergrationfacesEditField
        function rangeofintergrationfacesEditFieldValueChanged(app, event)
            Relcut = app.rangeofintergrationfacesEditField.Value;
            save('tmp/Relcut.mat','Relcut')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            imp_specY= app.YEditField.Value;
            save('tmp/imp_specY.mat','imp_specY')  
        end

        % Value changed function: XYnm2EditField
        function XYnm2EditFieldValueChanged(app, event)
            XYField = app.XYnm2EditField.Value;
            save('tmp/XYField.mat','XYField')  
        end

        % Value changed function: startvalueEditField
        function startvalueEditFieldValueChanged(app, event)
            cmSt = app.startvalueEditField.Value;
            save('tmp/cmSt.mat','cmSt')  
        end

        % Value changed function: endvalueEditField
        function endvalueEditFieldValueChanged(app, event)
            cmEn = app.endvalueEditField.Value;
            save('tmp/cmEn.mat','cmEn') 
        end

        % Value changed function: RadiusnmEditField
        function RadiusnmEditFieldValueChanged(app, event)
            detRad = app.RadiusnmEditField.Value;
            save('tmp/detRad.mat','detRad') 
        end

        % Value changed function: MeshEditField
        function MeshEditFieldValueChanged(app, event)
            detQ = app.MeshEditField.Value;
            save('tmp/detQ.mat','detQ') 
        end

        % Value changed function: EmissionanglefarfieldscanCheckBox
        function EmissionanglefarfieldscanCheckBoxValueChanged(app, event)
            CLang = double(app.EmissionanglefarfieldscanCheckBox.Value);
            save('tmp/CLang.mat','CLang') 
        end

        % Value changed function: EllipticitymapsCheckBox
        function EllipticitymapsCheckBoxValueChanged(app, event)
            Pol_map_en = app.EllipticitymapsCheckBox.Value;
            save('tmp/Pol_map_en.mat','Pol_map_en') 
        end

        % Value changed function: 
        % EmissionangleellipticityscanCheckBox
        function EmissionangleellipticityscanCheckBoxValueChanged(app, event)
            Pol_ang_en = app.EmissionangleellipticityscanCheckBox.Value;
            save('tmp/Pol_ang_en.mat','Pol_ang_en') 
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            Source_op = str2double(app.SourceDropDown.Value);
            save('tmp/Source_op.mat','Source_op') 
        end

        % Value changed function: PolarizationDropDown
        function PolarizationDropDownValueChanged(app, event)
            pw_pol = str2double(app.PolarizationDropDown.Value);
            save('tmp/pw_pol.mat','pw_pol') 
        end

        % Value changed function: OscillationdirectionDropDown
        function OscillationdirectionDropDownValueChanged(app, event)
            dip_d = str2double(app.OscillationdirectionDropDown.Value);
            save('tmp/dip_d.mat','dip_d') 
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            dip_x = app.XEditField.Value;
            save('tmp/dip_x.mat','dip_x') 
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            dip_y = app.YEditField_2.Value;
            save('tmp/dip_y.mat','dip_y') 
        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            dip_z = app.ZEditField_2.Value;
            save('tmp/dip_z.mat','dip_z')
        end

        % Value changed function: FermiLeveleVEditField
        function FermiLeveleVEditFieldValueChanged(app, event)
            Ef_1 = app.FermiLeveleVEditField.Value;
            save('tmp/Ef_1.mat','Ef_1')
            graphene_m( )
            mos2_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: Mobilitycm2VsEditField
        function Mobilitycm2VsEditFieldValueChanged(app, event)
            mobil = app.Mobilitycm2VsEditField.Value;
            save('tmp/mobil.mat','mobil')
            graphene_m( )
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: TemperatureKEditField
        function TemperatureKEditFieldValueChanged(app, event)
            T = app.TemperatureKEditField.Value;
            save('tmp/T.mat','T')
            graphene_m( )
            mos2_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: EnablelossesCheckBox
        function EnablelossesCheckBoxValueChanged(app, event)
            Drude = app.EnablelossesCheckBox.Value;
            save('tmp/Drude.mat','Drude')
            graphene_m( )
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: aroundxEditField
        function aroundxEditFieldValueChanged(app, event)
            xtilt = app.aroundxEditField.Value;
            save('tmp/xtilt.mat','xtilt')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: aroundyEditField
        function aroundyEditFieldValueChanged(app, event)
            ytilt = app.aroundyEditField.Value;
            save('tmp/ytilt.mat','ytilt')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: aroundzEditField
        function aroundzEditFieldValueChanged(app, event)
            ztilt = app.aroundzEditField.Value;
            save('tmp/ztilt.mat','ztilt')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: TiltscanCheckBox
        function TiltscanCheckBoxValueChanged(app, event)
            tilt_en = double(app.TiltscanCheckBox.Value);
            save('tmp/tilt_en.mat','tilt_en')
        end

        % Value changed function: DecayrateCheckBox
        function DecayrateCheckBoxValueChanged(app, event)
            decay_en = double(app.DecayrateCheckBox.Value);
            save('tmp/decay_en.mat','decay_en')
        end

        % Value changed function: LDOS1DCheckBox
        function LDOS1DCheckBoxValueChanged(app, event)
            LDOS_en = double(app.LDOS1DCheckBox.Value);
            save('tmp/LDOS_en.mat','LDOS_en')
        end

        % Value changed function: LDOS3DCheckBox
        function LDOS3DCheckBoxValueChanged(app, event)
            LDOS3D_en = double(app.LDOS3DCheckBox.Value);
            save('tmp/LDOS3D_en.mat','LDOS3D_en')
        end

        % Value changed function: DecaymapsCheckBox
        function DecaymapsCheckBoxValueChanged(app, event)
            Dmaps_en = double(app.DecaymapsCheckBox.Value);
            save('tmp/Dmaps_en.mat','Dmaps_en')
        end

        % Value changed function: PlasmafrequencyeVEditField
        function PlasmafrequencyeVEditFieldValueChanged(app, event)
            omega_p = app.PlasmafrequencyeVEditField.Value;
            save('tmp/omega_p.mat','omega_p')
            Drude_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: EpsiloninfinityEditField
        function EpsiloninfinityEditFieldValueChanged(app, event)
            eps_inf = app.EpsiloninfinityEditField.Value;
            save('tmp/eps_inf.mat','eps_inf')
            Drude_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: eVEditField
        function eVEditFieldValueChanged4(app, event)
            gamaeV = app.eVEditField.Value;
            save('tmp/gamaeV.mat','gamaeV')
            Drude_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: UserdefinedCheckBox
        function UserdefinedCheckBoxValueChanged(app, event)
            user_en = double(app.UserdefinedCheckBox.Value);
            save('tmp/user_en.mat','user_en')
        end

        % Value changed function: LuminescencemapsCheckBox
        function LuminescencemapsCheckBoxValueChanged(app, event)
            Ld_map_en = double(app.LuminescencemapsCheckBox.Value);
            save('tmp/Ld_map_en.mat','Ld_map_en')
        end

        % Value changed function: EllipticitymapsCheckBox_2
        function EllipticitymapsCheckBox_2ValueChanged(app, event)
            Pold_map_en = double(app.EllipticitymapsCheckBox_2.Value);
            save('tmp/Pold_map_en.mat','Pold_map_en')
        end

        % Value changed function: LumenhancementCheckBox
        function LumenhancementCheckBoxValueChanged(app, event)
            Enh_sp_en = double(app.LumenhancementCheckBox.Value);
            save('tmp/Enh_sp_en.mat','Enh_sp_en')
        end

        % Value changed function: LumenhancementmapsCheckBox
        function LumenhancementmapsCheckBoxValueChanged(app, event)
            Enh_map_en = double(app.LumenhancementmapsCheckBox.Value);
            save('tmp/Enh_map_en.mat','Enh_map_en')
        end

        % Value changed function: ReplacedipolewithQDCheckBox
        function ReplacedipolewithQDCheckBoxValueChanged(app, event)
            qd_en = double(app.ReplacedipolewithQDCheckBox.Value);
            save('tmp/qd_en.mat','qd_en')
            if app.saveFlag~=1
                strGUIplot(app)
            end
        end

        % Value changed function: peVEditField
        function peVEditFieldValueChanged(app, event)
            omega_pl = app.peVEditField.Value;
            save('tmp/omega_pl.mat','omega_pl')
            Lorentz_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: egeVEditField
        function egeVEditFieldValueChanged(app, event)
            omega_eg = app.egeVEditField.Value;
            save('tmp/omega_eg.mat','omega_eg')
            Lorentz_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: egeVEditField_2
        function egeVEditField_2ValueChanged(app, event)
            gama_eg = app.egeVEditField_2.Value;
            save('tmp/gama_eg.mat','gama_eg')
            Lorentz_m()
            [n_loc,k_loc, enei_loc,n_loc2,k_loc2, enei_loc2, n_loc3, k_loc3, enei_loc3]= refPlot();            
            plot(app.UIAxes, enei_loc, n_loc, enei_loc, k_loc)
            axis(app.UIAxes, 'tight');
            legend(app.UIAxes, 'n', 'k');  
            plot(app.UIAxes_2, enei_loc2, n_loc2, enei_loc2, k_loc2)
            axis(app.UIAxes_2, 'tight');
            legend(app.UIAxes_2, 'n', 'k');
            plot(app.UIAxes_3, enei_loc3, n_loc3, enei_loc3, k_loc3)
            axis(app.UIAxes_3, 'tight');
            legend(app.UIAxes_3, 'n', 'k');
        end

        % Value changed function: PolarizationrotationCheckBox
        function PolarizationrotationCheckBoxValueChanged(app, event)
            pol_rot = app.PolarizationrotationCheckBox.Value;
            save('tmp/pol_rot.mat','pol_rot')
        end

        % Button pushed function: DviewButton
        function DviewButtonPushed(app, event)
            openfig('data/str.fig','visible');       
            grid on            
        end

        % Button pushed function: DetectorButton
        function DetectorButtonPushed(app, event)
            mat = dir('tmp/*.mat');
            for q = 1:length(mat) 
                load(sprintf(['tmp/' mat(q).name])); 
            end   
            %% Detector input
            d_angle1=deg2rad(d_angle1a); % XY plane angle
            d_angle2=deg2rad(180-d_angle2a); % XZ angle
            d_range1=deg2rad(d_range1a); % Vertical to Z angle range
            angle_d1=[-pi, pi]; % Define detector sphere position 2
            angle_d2=[0, d_range1/2]; % Define detector sphere position
            pinfty_tmp = trispheresegment( linspace( angle_d1(1),  angle_d1(2), 51 ), linspace( angle_d2(1),  angle_d2(2), 51 ), detRad*2, 'interp', 'curv'  ); % Sphere segment at infinity
            pinfty_tmprot=(rot(pinfty_tmp,rad2deg(d_angle2),[0,1,0]));
            pinfty=(rot(pinfty_tmprot,rad2deg(d_angle1),[0,0,1]));
            openfig('data/str.fig','visible');
            hold on
            plot(pinfty)
            grid on
            axis on
            axis([-detRad detRad -detRad detRad -detRad detRad])
            xlabel('x (nm)')
            ylabel('y (nm)')
            zlabel('z (nm)')
            title('Detector')
        end

        % Value changed function: Dir_xEditField
        function Dir_xEditFieldValueChanged(app, event)
            dir_x = app.Dir_xEditField.Value;
            save('tmp/dir_x.mat','dir_x')
        end

        % Value changed function: Dir_yEditField
        function Dir_yEditFieldValueChanged(app, event)
            dir_y = app.Dir_yEditField.Value;
            save('tmp/dir_y.mat','dir_y')
        end

        % Value changed function: Dir_zEditField
        function Dir_zEditFieldValueChanged(app, event)
            dir_z = app.Dir_zEditField.Value;
            save('tmp/dir_z.mat','dir_z')
        end

        % Value changed function: ExEditField
        function ExEditFieldValueChanged(app, event)
            jones_x = str2num(app.ExEditField.Value);
            save('tmp/jones_x.mat','jones_x') 
        end

        % Value changed function: EyEditField
        function EyEditFieldValueChanged(app, event)
            jones_y = str2num(app.EyEditField.Value);
            save('tmp/jones_y.mat','jones_y') 
        end

        % Value changed function: EzEditField
        function EzEditFieldValueChanged(app, event)
            jones_z = str2num(app.EzEditField.Value);
            save('tmp/jones_z.mat','jones_z') 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CLmap = uifigure('Visible', 'off');
            app.CLmap.Name = 'UI Figure';
            app.QuickLoadMenu = uimenu(app.CLmap);
            app.QuickLoadMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickLoadMenuSelected, true);
            app.QuickLoadMenu.Text = 'Quick Load';
            app.Menu_2 = uimenu(app.CLmap);
            app.Menu_2.Enable = 'off';
            app.Menu_2.Text = '--';
            app.QuickSaveMenu = uimenu(app.CLmap);
            app.QuickSaveMenu.MenuSelectedFcn = createCallbackFcn(app, @QuickSaveMenuSelected, true);
            app.QuickSaveMenu.Text = 'Quick Save';
            app.Menu = uimenu(app.CLmap);
            app.Menu.Enable = 'off';
            app.Menu.Text = '------';
            app.LoadMenu = uimenu(app.CLmap);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.Menu_3 = uimenu(app.CLmap);
            app.Menu_3.Enable = 'off';
            app.Menu_3.Text = '--';
            app.SaveasMenu = uimenu(app.CLmap);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.Menu_4 = uimenu(app.CLmap);
            app.Menu_4.Enable = 'off';
            app.Menu_4.Text = '------';
            app.SaveresultsMenu = uimenu(app.CLmap);
            app.SaveresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveresultsMenuSelected, true);
            app.SaveresultsMenu.Text = 'Save results';
            app.RunButton = uibutton(app.CLmap, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SolveroptionsPanel = uipanel(app.CLmap);
            app.SolveroptionsPanel.Title = 'Solver options';
            app.SolverDropDownLabel = uilabel(app.SolveroptionsPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.SolveroptionsPanel);
            app.SolverDropDown.Items = {'Retarded', 'Quasistatic', 'Iterative'};
            app.SolverDropDown.ItemsData = {'0', '1', '2'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = '0';
            app.CalculationTogglePanel = uipanel(app.CLmap);
            app.CalculationTogglePanel.Title = 'Calculation Toggle';
            app.TabGroup6 = uitabgroup(app.CalculationTogglePanel);
            app.CommonTab = uitab(app.TabGroup6);
            app.CommonTab.Title = 'Common';
            app.ChargemapsCheckBox = uicheckbox(app.CommonTab);
            app.ChargemapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChargemapsCheckBoxValueChanged, true);
            app.ChargemapsCheckBox.Text = 'Charge maps';
            app.FieldmapsCheckBox = uicheckbox(app.CommonTab);
            app.FieldmapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FieldmapsCheckBoxValueChanged, true);
            app.FieldmapsCheckBox.Text = 'Field maps';
            app.RadiativepatternsCheckBox = uicheckbox(app.CommonTab);
            app.RadiativepatternsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RadiativepatternsCheckBoxValueChanged, true);
            app.RadiativepatternsCheckBox.Text = 'Radiative patterns';
            app.FarfieldspectrascatteringCheckBox = uicheckbox(app.CommonTab);
            app.FarfieldspectrascatteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @FarfieldspectrascatteringCheckBoxValueChanged, true);
            app.FarfieldspectrascatteringCheckBox.Text = 'Farfield spectra / scattering';
            app.EmissionanglefarfieldscanCheckBox = uicheckbox(app.CommonTab);
            app.EmissionanglefarfieldscanCheckBox.ValueChangedFcn = createCallbackFcn(app, @EmissionanglefarfieldscanCheckBoxValueChanged, true);
            app.EmissionanglefarfieldscanCheckBox.Text = 'Emission angle farfield scan';
            app.EmissionangleellipticityscanCheckBox = uicheckbox(app.CommonTab);
            app.EmissionangleellipticityscanCheckBox.ValueChangedFcn = createCallbackFcn(app, @EmissionangleellipticityscanCheckBoxValueChanged, true);
            app.EmissionangleellipticityscanCheckBox.Text = 'Emission angle ellipticity scan';
            app.TiltscanCheckBox = uicheckbox(app.CommonTab);
            app.TiltscanCheckBox.ValueChangedFcn = createCallbackFcn(app, @TiltscanCheckBoxValueChanged, true);
            app.TiltscanCheckBox.Text = 'Tilt scan';
            app.UserdefinedCheckBox = uicheckbox(app.CommonTab);
            app.UserdefinedCheckBox.ValueChangedFcn = createCallbackFcn(app, @UserdefinedCheckBoxValueChanged, true);
            app.UserdefinedCheckBox.Text = 'User defined';
            app.EBeamTab = uitab(app.TabGroup6);
            app.EBeamTab.Title = 'E-Beam';
            app.CathodoluminescencemapsCheckBox = uicheckbox(app.EBeamTab);
            app.CathodoluminescencemapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CathodoluminescencemapsCheckBoxValueChanged, true);
            app.CathodoluminescencemapsCheckBox.Text = 'Cathodoluminescence maps';
            app.EELSmapsCheckBox = uicheckbox(app.EBeamTab);
            app.EELSmapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EELSmapsCheckBoxValueChanged, true);
            app.EELSmapsCheckBox.Text = 'EELS maps';
            app.EllipticitymapsCheckBox = uicheckbox(app.EBeamTab);
            app.EllipticitymapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EllipticitymapsCheckBoxValueChanged, true);
            app.EllipticitymapsCheckBox.Text = 'Ellipticity maps';
            app.PWTab = uitab(app.TabGroup6);
            app.PWTab.Title = 'PW';
            app.PolarizationrotationCheckBox = uicheckbox(app.PWTab);
            app.PolarizationrotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolarizationrotationCheckBoxValueChanged, true);
            app.PolarizationrotationCheckBox.Text = 'Polarization rotation';
            app.DipoleTab_2 = uitab(app.TabGroup6);
            app.DipoleTab_2.Title = 'Dipole';
            app.DecayrateCheckBox = uicheckbox(app.DipoleTab_2);
            app.DecayrateCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecayrateCheckBoxValueChanged, true);
            app.DecayrateCheckBox.Text = 'Decay rate';
            app.LDOS1DCheckBox = uicheckbox(app.DipoleTab_2);
            app.LDOS1DCheckBox.ValueChangedFcn = createCallbackFcn(app, @LDOS1DCheckBoxValueChanged, true);
            app.LDOS1DCheckBox.Text = 'LDOS 1D';
            app.LDOS3DCheckBox = uicheckbox(app.DipoleTab_2);
            app.LDOS3DCheckBox.ValueChangedFcn = createCallbackFcn(app, @LDOS3DCheckBoxValueChanged, true);
            app.LDOS3DCheckBox.Text = 'LDOS 3D';
            app.DecaymapsCheckBox = uicheckbox(app.DipoleTab_2);
            app.DecaymapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecaymapsCheckBoxValueChanged, true);
            app.DecaymapsCheckBox.Text = 'Decay maps';
            app.LumenhancementCheckBox = uicheckbox(app.DipoleTab_2);
            app.LumenhancementCheckBox.ValueChangedFcn = createCallbackFcn(app, @LumenhancementCheckBoxValueChanged, true);
            app.LumenhancementCheckBox.Text = 'Lum. enhancement';
            app.LumenhancementmapsCheckBox = uicheckbox(app.DipoleTab_2);
            app.LumenhancementmapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LumenhancementmapsCheckBoxValueChanged, true);
            app.LumenhancementmapsCheckBox.Text = 'Lum. enhancement maps';
            app.LuminescencemapsCheckBox = uicheckbox(app.DipoleTab_2);
            app.LuminescencemapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LuminescencemapsCheckBoxValueChanged, true);
            app.LuminescencemapsCheckBox.Text = 'Luminescence maps';
            app.EllipticitymapsCheckBox_2 = uicheckbox(app.DipoleTab_2);
            app.EllipticitymapsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EllipticitymapsCheckBox_2ValueChanged, true);
            app.EllipticitymapsCheckBox_2.Text = 'Ellipticity maps';
            app.StructureoptionsPanel = uipanel(app.CLmap);
            app.StructureoptionsPanel.Title = 'Structure options';
            app.StructureDropDownLabel = uilabel(app.StructureoptionsPanel);
            app.StructureDropDownLabel.Text = 'Structure';
            app.StructureDropDown = uidropdown(app.StructureoptionsPanel);
            app.StructureDropDown.Items = {'Sphere', 'Disk', 'Triangle', 'Polygon', 'Vertical nanorod', 'Horizontal nanorod', 'Ellipsoid', 'Cude', 'Torus', 'Torus 2', 'Nanohole', 'User defined'};
            app.StructureDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.StructureDropDown.ValueChangedFcn = createCallbackFcn(app, @StructureDropDownValueChanged, true);
            app.StructureDropDown.Value = '0';
            app.DiameterLengthnmEditFieldLabel = uilabel(app.StructureoptionsPanel);
            app.DiameterLengthnmEditFieldLabel.Text = 'Diameter/Length (nm)';
            app.DiameterLengthnmEditField = uieditfield(app.StructureoptionsPanel, 'numeric');
            app.DiameterLengthnmEditField.Limits = [0 Inf];
            app.DiameterLengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterLengthnmEditFieldValueChanged, true);
            app.DiameterLengthnmEditField.Value = 200;
            app.HeightnmEditFieldLabel = uilabel(app.StructureoptionsPanel);
            app.HeightnmEditFieldLabel.Text = 'Height (nm)';
            app.HeightnmEditField = uieditfield(app.StructureoptionsPanel, 'numeric');
            app.HeightnmEditField.Limits = [0 Inf];
            app.HeightnmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightnmEditFieldValueChanged, true);
            app.HeightnmEditField.Value = 40;
            app.WidthnmEditField_2Label = uilabel(app.StructureoptionsPanel);
            app.WidthnmEditField_2Label.Text = 'Width (nm)';
            app.WidthnmEditField_2 = uieditfield(app.StructureoptionsPanel, 'numeric');
            app.WidthnmEditField_2.Limits = [0 Inf];
            app.WidthnmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthnmEditField_2ValueChanged, true);
            app.WidthnmEditField_2.Value = 200;
            app.TabGroup2 = uitabgroup(app.StructureoptionsPanel);
            app.MeshTab = uitab(app.TabGroup2);
            app.MeshTab.Title = 'Mesh';
            app.MeshresolutionPanel = uipanel(app.MeshTab);
            app.MeshresolutionPanel.Title = 'Mesh resolution';
            app.ntriangulationpointsEditFieldLabel = uilabel(app.MeshresolutionPanel);
            app.ntriangulationpointsEditFieldLabel.Text = 'n triangulation points';
            app.ntriangulationpointsEditField = uieditfield(app.MeshresolutionPanel, 'numeric');
            app.ntriangulationpointsEditField.Limits = [1 10000];
            app.ntriangulationpointsEditField.ValueChangedFcn = createCallbackFcn(app, @ntriangulationpointsEditFieldValueChanged, true);
            app.ntriangulationpointsEditField.Value = 50;
            app.nfacesedgeEditFieldLabel = uilabel(app.MeshresolutionPanel);
            app.nfacesedgeEditFieldLabel.Text = 'n faces edge';
            app.nfacesedgeEditField = uieditfield(app.MeshresolutionPanel, 'numeric');
            app.nfacesedgeEditField.Limits = [1 10000];
            app.nfacesedgeEditField.ValueChangedFcn = createCallbackFcn(app, @nfacesedgeEditFieldValueChanged, true);
            app.nfacesedgeEditField.Value = 10;
            app.maxelementsizeEditFieldLabel = uilabel(app.MeshresolutionPanel);
            app.maxelementsizeEditFieldLabel.Text = 'max element size ';
            app.maxelementsizeEditField = uieditfield(app.MeshresolutionPanel, 'numeric');
            app.maxelementsizeEditField.Limits = [1 100];
            app.maxelementsizeEditField.ValueChangedFcn = createCallbackFcn(app, @maxelementsizeEditFieldValueChanged, true);
            app.maxelementsizeEditField.Value = 15;
            app.integrationpointsEditFieldLabel = uilabel(app.MeshresolutionPanel);
            app.integrationpointsEditFieldLabel.Text = 'integration points';
            app.integrationpointsEditField = uieditfield(app.MeshresolutionPanel, 'numeric');
            app.integrationpointsEditField.Limits = [1 40];
            app.integrationpointsEditField.ValueChangedFcn = createCallbackFcn(app, @integrationpointsEditFieldValueChanged3, true);
            app.integrationpointsEditField.Value = 1;
            app.diagonalintegrationpointsEditFieldLabel = uilabel(app.MeshresolutionPanel);
            app.diagonalintegrationpointsEditFieldLabel.Text = 'diagonal-integration points';
            app.diagonalintegrationpointsEditField = uieditfield(app.MeshresolutionPanel, 'numeric');
            app.diagonalintegrationpointsEditField.Limits = [1 150];
            app.diagonalintegrationpointsEditField.ValueChangedFcn = createCallbackFcn(app, @diagonalintegrationpointsEditFieldValueChanged2, true);
            app.diagonalintegrationpointsEditField.Value = 3;
            app.rangeofintergrationfacesEditFieldLabel = uilabel(app.MeshresolutionPanel);
            app.rangeofintergrationfacesEditFieldLabel.Text = 'range of intergration faces';
            app.rangeofintergrationfacesEditField = uieditfield(app.MeshresolutionPanel, 'numeric');
            app.rangeofintergrationfacesEditField.Limits = [3 10];
            app.rangeofintergrationfacesEditField.ValueChangedFcn = createCallbackFcn(app, @rangeofintergrationfacesEditFieldValueChanged, true);
            app.rangeofintergrationfacesEditField.Value = 3;
            app.SubstrateTab = uitab(app.TabGroup2);
            app.SubstrateTab.Title = 'Substrate';
            app.SubstrateoptionsPanel = uipanel(app.SubstrateTab);
            app.SubstrateoptionsPanel.Title = 'Substrate options';
            app.SubstratelengthnmEditFieldLabel = uilabel(app.SubstrateoptionsPanel);
            app.SubstratelengthnmEditFieldLabel.Text = 'Substrate length (nm)';
            app.SubstratelengthnmEditField = uieditfield(app.SubstrateoptionsPanel, 'numeric');
            app.SubstratelengthnmEditField.Limits = [0 1000];
            app.SubstratelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @SubstratelengthnmEditFieldValueChanged, true);
            app.SubstratelengthnmEditField.Value = 200;
            app.SubstratethicknessnmEditFieldLabel = uilabel(app.SubstrateoptionsPanel);
            app.SubstratethicknessnmEditFieldLabel.Text = 'Substrate thickness (nm)';
            app.SubstratethicknessnmEditField = uieditfield(app.SubstrateoptionsPanel, 'numeric');
            app.SubstratethicknessnmEditField.Limits = [0 500];
            app.SubstratethicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @SubstratethicknessnmEditFieldValueChanged, true);
            app.SubstratethicknessnmEditField.Value = 15;
            app.SubstrateCheckBox = uicheckbox(app.SubstrateoptionsPanel);
            app.SubstrateCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubstrateCheckBoxValueChanged, true);
            app.SubstrateCheckBox.Text = 'Substrate';
            app.Substrate2CheckBox = uicheckbox(app.SubstrateoptionsPanel);
            app.Substrate2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Substrate2CheckBoxValueChanged, true);
            app.Substrate2CheckBox.Text = 'Substrate 2';
            app.Substrate2thicknessnmEditFieldLabel = uilabel(app.SubstrateoptionsPanel);
            app.Substrate2thicknessnmEditFieldLabel.Text = 'Substrate 2 thickness (nm)';
            app.Substrate2thicknessnmEditField = uieditfield(app.SubstrateoptionsPanel, 'numeric');
            app.Substrate2thicknessnmEditField.Limits = [0 500];
            app.Substrate2thicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @Substrate2thicknessnmEditFieldValueChanged, true);
            app.Substrate2thicknessnmEditField.Value = 15;
            app.ExtraTab = uitab(app.TabGroup2);
            app.ExtraTab.Title = 'Extra';
            app.AdditionaloptionsPanel = uipanel(app.ExtraTab);
            app.AdditionaloptionsPanel.Title = 'Additional options';
            app.DoubleStructureCheckBox = uicheckbox(app.AdditionaloptionsPanel);
            app.DoubleStructureCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoubleStructureCheckBoxValueChanged, true);
            app.DoubleStructureCheckBox.Text = 'Double Structure';
            app.CoverlayerCheckBox = uicheckbox(app.AdditionaloptionsPanel);
            app.CoverlayerCheckBox.ValueChangedFcn = createCallbackFcn(app, @CoverlayerCheckBoxValueChanged, true);
            app.CoverlayerCheckBox.Text = 'Cover layer';
            app.ThicknessnmEditFieldLabel = uilabel(app.AdditionaloptionsPanel);
            app.ThicknessnmEditFieldLabel.Text = 'Thickness (nm)';
            app.ThicknessnmEditField = uieditfield(app.AdditionaloptionsPanel, 'numeric');
            app.ThicknessnmEditField.Limits = [0 Inf];
            app.ThicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessnmEditFieldValueChanged, true);
            app.ThicknessnmEditField.Value = 20;
            app.ShiftnmEditFieldLabel = uilabel(app.AdditionaloptionsPanel);
            app.ShiftnmEditFieldLabel.Text = 'Shift (nm)';
            app.ShiftnmEditField = uieditfield(app.AdditionaloptionsPanel, 'numeric');
            app.ShiftnmEditField.Limits = [0 Inf];
            app.ShiftnmEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftnmEditFieldValueChanged, true);
            app.ShiftnmEditField.Value = 105;
            app.PolygonnEditFieldLabel = uilabel(app.AdditionaloptionsPanel);
            app.PolygonnEditFieldLabel.Text = 'Polygon n';
            app.PolygonnEditField = uieditfield(app.AdditionaloptionsPanel, 'numeric');
            app.PolygonnEditField.Limits = [3 100];
            app.PolygonnEditField.ValueChangedFcn = createCallbackFcn(app, @PolygonnEditFieldValueChanged, true);
            app.PolygonnEditField.Value = 6;
            app.TiltTab = uitab(app.TabGroup2);
            app.TiltTab.Title = 'Tilt';
            app.TiltangleDegreesPanel = uipanel(app.TiltTab);
            app.TiltangleDegreesPanel.Title = 'Tilt angle (Degrees)';
            app.aroundxEditFieldLabel = uilabel(app.TiltangleDegreesPanel);
            app.aroundxEditFieldLabel.Text = 'around x';
            app.aroundxEditField = uieditfield(app.TiltangleDegreesPanel, 'numeric');
            app.aroundxEditField.Limits = [0 360];
            app.aroundxEditField.ValueChangedFcn = createCallbackFcn(app, @aroundxEditFieldValueChanged, true);
            app.aroundyEditFieldLabel = uilabel(app.TiltangleDegreesPanel);
            app.aroundyEditFieldLabel.Text = 'around y';
            app.aroundyEditField = uieditfield(app.TiltangleDegreesPanel, 'numeric');
            app.aroundyEditField.Limits = [0 360];
            app.aroundyEditField.ValueChangedFcn = createCallbackFcn(app, @aroundyEditFieldValueChanged, true);
            app.aroundzEditFieldLabel = uilabel(app.TiltangleDegreesPanel);
            app.aroundzEditFieldLabel.Text = 'around z';
            app.aroundzEditField = uieditfield(app.TiltangleDegreesPanel, 'numeric');
            app.aroundzEditField.Limits = [0 360];
            app.aroundzEditField.ValueChangedFcn = createCallbackFcn(app, @aroundzEditFieldValueChanged, true);
            app.PermittivityoptionsPanel = uipanel(app.CLmap);
            app.PermittivityoptionsPanel.Title = 'Permittivity options';
            app.TabGroup4 = uitabgroup(app.PermittivityoptionsPanel);
            app.TabGroup5 = uitabgroup(app.PermittivityoptionsPanel);
            app.StructureTab = uitab(app.TabGroup5);
            app.StructureTab.Title = 'Structure';
            app.CheckBox = uicheckbox(app.StructureTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.StructureTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.StructureTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.ofenvironmentEditFieldLabel = uilabel(app.StructureTab);
            app.ofenvironmentEditFieldLabel.Text = 'ε of environment';
            app.ofenvironmentEditField = uieditfield(app.StructureTab, 'numeric');
            app.ofenvironmentEditField.ValueChangedFcn = createCallbackFcn(app, @ofenvironmentEditFieldValueChanged, true);
            app.ofenvironmentEditField.Value = 1;
            app.ofstructure2EditFieldLabel = uilabel(app.StructureTab);
            app.ofstructure2EditFieldLabel.Text = 'ε  of structure 2 ';
            app.ofstructure2EditField = uieditfield(app.StructureTab, 'numeric');
            app.ofstructure2EditField.ValueChangedFcn = createCallbackFcn(app, @ofstructure2EditFieldValueChanged, true);
            app.ofstructure2EditField.Value = 1;
            app.orDropDownLabel = uilabel(app.StructureTab);
            app.orDropDownLabel.Text = 'or';
            app.orDropDown = uidropdown(app.StructureTab);
            app.orDropDown.Items = {'Copper', 'Gold', 'Gold Palik', 'Silicon Schinke', 'Silicon Aspnes', 'Silver', 'Silver Palik', 'Graphene', 'MoS2', 'Aluminum', 'Drude', 'Lorentz', 'User defined'};
            app.orDropDown.ItemsData = {'copperpalik.dat', 'gold.dat', 'goldpalik.dat', 'siliconSchi.dat', 'siliconAsp.dat', 'silver.dat', 'silverpalik.dat', 'Graphene.dat', 'mos2.dat', 'Aluminum.dat', 'Drude.dat', 'Lorentz.dat', 'user1.dat'};
            app.orDropDown.ValueChangedFcn = createCallbackFcn(app, @orDropDownValueChanged, true);
            app.orDropDown.Value = 'gold.dat';
            app.ofstructure1EditFieldLabel = uilabel(app.StructureTab);
            app.ofstructure1EditFieldLabel.Text = 'ε  of structure 1';
            app.ofstructure1EditField = uieditfield(app.StructureTab, 'numeric');
            app.ofstructure1EditField.Limits = [-200 200];
            app.ofstructure1EditField.ValueChangedFcn = createCallbackFcn(app, @ofstructure1EditFieldValueChanged, true);
            app.ofstructure1EditField.Value = 5;
            app.orDropDown_2Label = uilabel(app.StructureTab);
            app.orDropDown_2Label.Text = 'or';
            app.orDropDown_2 = uidropdown(app.StructureTab);
            app.orDropDown_2.Items = {'Copper', 'Gold', 'Gold Palik', 'Silicon Schinke', 'Silicon Aspnes', 'Silver', 'Silver Palik', 'Graphene', 'MoS2', 'Aluminum', 'Drude', 'Lorentz', 'User defined'};
            app.orDropDown_2.ItemsData = {'copperpalik.dat', 'gold.dat', 'goldpalik.dat', 'siliconSchi.dat', 'siliconAsp.dat', 'silver.dat', 'silverpalik.dat', 'Graphene.dat', 'mos2.dat', 'Aluminum.dat', 'Drude.dat', 'Lorentz.dat', 'user2.dat'};
            app.orDropDown_2.ValueChangedFcn = createCallbackFcn(app, @orDropDown_2ValueChanged, true);
            app.orDropDown_2.Value = 'gold.dat';
            app.ofstructure3EditFieldLabel = uilabel(app.StructureTab);
            app.ofstructure3EditFieldLabel.Text = 'ε  of structure 3';
            app.ofstructure3EditField = uieditfield(app.StructureTab, 'numeric');
            app.ofstructure3EditField.ValueChangedFcn = createCallbackFcn(app, @ofstructure3EditFieldValueChanged, true);
            app.orDropDown_3Label = uilabel(app.StructureTab);
            app.orDropDown_3Label.Text = 'or';
            app.orDropDown_3 = uidropdown(app.StructureTab);
            app.orDropDown_3.Items = {'Copper', 'Gold', 'Gold Palik', 'Silicon Schinke', 'Silicon Aspnes', 'Silver', 'Silver Palik', 'Graphene', 'MoS2', 'Aluminum', 'Drude', 'Lorentz', 'User defined'};
            app.orDropDown_3.ItemsData = {'copperpalik.dat', 'gold.dat', 'goldpalik.dat', 'siliconSchi.dat', 'siliconAsp.dat', 'silver.dat', 'silverpalik.dat', 'Graphene.dat', 'mos2.dat', 'Aluminum.dat', 'Drude.dat', 'Lorentz.dat', 'user3.dat'};
            app.orDropDown_3.ValueChangedFcn = createCallbackFcn(app, @orDropDown_3ValueChanged, true);
            app.orDropDown_3.Value = 'gold.dat';
            app.GrapheneMoS2Tab = uitab(app.TabGroup5);
            app.GrapheneMoS2Tab.Title = 'Graphene / MoS2';
            app.FermiLeveleVEditFieldLabel = uilabel(app.GrapheneMoS2Tab);
            app.FermiLeveleVEditFieldLabel.Text = 'Fermi Level (eV)';
            app.FermiLeveleVEditField = uieditfield(app.GrapheneMoS2Tab, 'numeric');
            app.FermiLeveleVEditField.Limits = [0 3];
            app.FermiLeveleVEditField.ValueChangedFcn = createCallbackFcn(app, @FermiLeveleVEditFieldValueChanged, true);
            app.FermiLeveleVEditField.Value = 0.1;
            app.Mobilitycm2VsEditFieldLabel = uilabel(app.GrapheneMoS2Tab);
            app.Mobilitycm2VsEditFieldLabel.Text = 'Mobility (cm^2/V.s)';
            app.Mobilitycm2VsEditField = uieditfield(app.GrapheneMoS2Tab, 'numeric');
            app.Mobilitycm2VsEditField.Limits = [0 Inf];
            app.Mobilitycm2VsEditField.ValueChangedFcn = createCallbackFcn(app, @Mobilitycm2VsEditFieldValueChanged, true);
            app.Mobilitycm2VsEditField.Value = 30000;
            app.TemperatureKEditFieldLabel = uilabel(app.GrapheneMoS2Tab);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.GrapheneMoS2Tab, 'numeric');
            app.TemperatureKEditField.Limits = [0 Inf];
            app.TemperatureKEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureKEditFieldValueChanged, true);
            app.TemperatureKEditField.Value = 300;
            app.EnablelossesCheckBox = uicheckbox(app.GrapheneMoS2Tab);
            app.EnablelossesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablelossesCheckBoxValueChanged, true);
            app.EnablelossesCheckBox.Text = 'Enable losses';
            app.DrudeTab = uitab(app.TabGroup5);
            app.DrudeTab.Title = 'Drude';
            app.PlasmafrequencyeVEditFieldLabel = uilabel(app.DrudeTab);
            app.PlasmafrequencyeVEditFieldLabel.Text = 'Plasma frequency (eV)';
            app.PlasmafrequencyeVEditField = uieditfield(app.DrudeTab, 'numeric');
            app.PlasmafrequencyeVEditField.Limits = [0 Inf];
            app.PlasmafrequencyeVEditField.ValueChangedFcn = createCallbackFcn(app, @PlasmafrequencyeVEditFieldValueChanged, true);
            app.PlasmafrequencyeVEditField.Value = 2.47;
            app.EpsiloninfinityEditFieldLabel = uilabel(app.DrudeTab);
            app.EpsiloninfinityEditFieldLabel.Text = 'Epsilon infinity ';
            app.EpsiloninfinityEditField = uieditfield(app.DrudeTab, 'numeric');
            app.EpsiloninfinityEditField.ValueChangedFcn = createCallbackFcn(app, @EpsiloninfinityEditFieldValueChanged, true);
            app.EpsiloninfinityEditField.Value = 3.9;
            app.eVEditFieldLabel = uilabel(app.DrudeTab);
            app.eVEditFieldLabel.Text = 'Γ  (eV)';
            app.eVEditField = uieditfield(app.DrudeTab, 'numeric');
            app.eVEditField.Limits = [0 Inf];
            app.eVEditField.ValueChangedFcn = createCallbackFcn(app, @eVEditFieldValueChanged4, true);
            app.eVEditField.Value = 0.062;
            app.LorentzTab = uitab(app.TabGroup5);
            app.LorentzTab.Title = 'Lorentz';
            app.peVEditFieldLabel = uilabel(app.LorentzTab);
            app.peVEditFieldLabel.Text = 'ωp (eV)';
            app.peVEditField = uieditfield(app.LorentzTab, 'numeric');
            app.peVEditField.Limits = [0 Inf];
            app.peVEditField.ValueChangedFcn = createCallbackFcn(app, @peVEditFieldValueChanged, true);
            app.peVEditField.Value = 2;
            app.egeVEditFieldLabel = uilabel(app.LorentzTab);
            app.egeVEditFieldLabel.Text = 'ωeg (eV)';
            app.egeVEditField = uieditfield(app.LorentzTab, 'numeric');
            app.egeVEditField.Limits = [0 Inf];
            app.egeVEditField.ValueChangedFcn = createCallbackFcn(app, @egeVEditFieldValueChanged, true);
            app.egeVEditField.Value = 1.4;
            app.egeVEditField_2Label = uilabel(app.LorentzTab);
            app.egeVEditField_2Label.Text = 'γeg (eV)';
            app.egeVEditField_2 = uieditfield(app.LorentzTab, 'numeric');
            app.egeVEditField_2.Limits = [0 Inf];
            app.egeVEditField_2.ValueChangedFcn = createCallbackFcn(app, @egeVEditField_2ValueChanged, true);
            app.egeVEditField_2.Value = 0.062;
            app.SourceandDetectorInputPanel = uipanel(app.CLmap);
            app.SourceandDetectorInputPanel.Title = 'Source and Detector Input';
            app.TabGroup = uitabgroup(app.SourceandDetectorInputPanel);
            app.EnergyrangeTab = uitab(app.TabGroup);
            app.EnergyrangeTab.Title = 'Energy range';
            app.EnergyRangeeVPanel = uipanel(app.EnergyrangeTab);
            app.EnergyRangeeVPanel.Title = 'Energy Range (eV)';
            app.MinEditField_2Label = uilabel(app.EnergyRangeeVPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.EnergyRangeeVPanel, 'numeric');
            app.MinEditField_2.Limits = [0 20];
            app.MinEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinEditField_2ValueChanged, true);
            app.MinEditField_2.Value = 1.2;
            app.MaxEditField_2Label = uilabel(app.EnergyRangeeVPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.EnergyRangeeVPanel, 'numeric');
            app.MaxEditField_2.Limits = [0 20];
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MaxEditField_2.Value = 4;
            app.StepsEditField_2Label = uilabel(app.EnergyRangeeVPanel);
            app.StepsEditField_2Label.Text = 'Steps';
            app.StepsEditField_2 = uieditfield(app.EnergyRangeeVPanel, 'numeric');
            app.StepsEditField_2.Limits = [1 1000];
            app.StepsEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepsEditField_2ValueChanged, true);
            app.StepsEditField_2.Value = 50;
            app.DetectorTab = uitab(app.TabGroup);
            app.DetectorTab.Title = 'Detector';
            app.DetectoranglePanel = uipanel(app.DetectorTab);
            app.DetectoranglePanel.Title = 'Detector angle °';
            app.XYplaneEditFieldLabel = uilabel(app.DetectoranglePanel);
            app.XYplaneEditFieldLabel.Text = 'XY plane';
            app.XYplaneEditField = uieditfield(app.DetectoranglePanel, 'numeric');
            app.XYplaneEditField.Limits = [0 360];
            app.XYplaneEditField.ValueChangedFcn = createCallbackFcn(app, @XYplaneEditFieldValueChanged, true);
            app.XZplaneEditFieldLabel = uilabel(app.DetectoranglePanel);
            app.XZplaneEditFieldLabel.Text = 'XZ plane';
            app.XZplaneEditField = uieditfield(app.DetectoranglePanel, 'numeric');
            app.XZplaneEditField.Limits = [0 180];
            app.XZplaneEditField.ValueChangedFcn = createCallbackFcn(app, @XZplaneEditFieldValueChanged, true);
            app.XZplaneEditField.Value = 90;
            app.DetectorrangePanel = uipanel(app.DetectoranglePanel);
            app.DetectorrangePanel.Title = 'Detector range °';
            app.RangeLabel = uilabel(app.DetectorrangePanel);
            app.RangeLabel.Text = 'Range°';
            app.RangeEditField = uieditfield(app.DetectorrangePanel, 'numeric');
            app.RangeEditField.Limits = [-360 360];
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.RangeEditField.Value = 15;
            app.RadiusnmEditFieldLabel = uilabel(app.DetectoranglePanel);
            app.RadiusnmEditFieldLabel.Text = 'Radius (nm)';
            app.RadiusnmEditField = uieditfield(app.DetectoranglePanel, 'numeric');
            app.RadiusnmEditField.Limits = [0.5 Inf];
            app.RadiusnmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusnmEditFieldValueChanged, true);
            app.RadiusnmEditField.Value = 0.5;
            app.MeshEditFieldLabel = uilabel(app.DetectoranglePanel);
            app.MeshEditFieldLabel.Text = 'Mesh';
            app.MeshEditField = uieditfield(app.DetectoranglePanel, 'numeric');
            app.MeshEditField.Limits = [30 20000];
            app.MeshEditField.ValueChangedFcn = createCallbackFcn(app, @MeshEditFieldValueChanged, true);
            app.MeshEditField.Value = 1000;
            app.TextArea_6 = uitextarea(app.DetectoranglePanel);
            app.TextArea_6.Value = {'For accurate CL probability set Radius = 0.5'};
            app.Mapspatialrange0isautoPanel = uipanel(app.DetectorTab);
            app.Mapspatialrange0isautoPanel.Title = 'Map spatial range (0 is auto)';
            app.XYnm2EditFieldLabel = uilabel(app.Mapspatialrange0isautoPanel);
            app.XYnm2EditFieldLabel.Text = 'XY (nm^2) ';
            app.XYnm2EditField = uieditfield(app.Mapspatialrange0isautoPanel, 'numeric');
            app.XYnm2EditField.Limits = [0 Inf];
            app.XYnm2EditField.ValueChangedFcn = createCallbackFcn(app, @XYnm2EditFieldValueChanged, true);
            app.ColormaprangeforField0valueforbothisautoPanel = uipanel(app.DetectorTab);
            app.ColormaprangeforField0valueforbothisautoPanel.Title = 'Colormap range for Field (0 value for both is auto)';
            app.startvalueEditFieldLabel = uilabel(app.ColormaprangeforField0valueforbothisautoPanel);
            app.startvalueEditFieldLabel.Text = 'start value';
            app.startvalueEditField = uieditfield(app.ColormaprangeforField0valueforbothisautoPanel, 'numeric');
            app.startvalueEditField.ValueChangedFcn = createCallbackFcn(app, @startvalueEditFieldValueChanged, true);
            app.endvalueEditFieldLabel = uilabel(app.ColormaprangeforField0valueforbothisautoPanel);
            app.endvalueEditFieldLabel.Text = 'end value';
            app.endvalueEditField = uieditfield(app.ColormaprangeforField0valueforbothisautoPanel, 'numeric');
            app.endvalueEditField.ValueChangedFcn = createCallbackFcn(app, @endvalueEditFieldValueChanged, true);
            app.BeamTab = uitab(app.TabGroup);
            app.BeamTab.Title = 'Beam';
            app.BeamoptionsPanel = uipanel(app.BeamTab);
            app.BeamoptionsPanel.Title = 'Beam options';
            app.KineticenergyeVEditFieldLabel = uilabel(app.BeamoptionsPanel);
            app.KineticenergyeVEditFieldLabel.Text = 'Kinetic energy (eV)';
            app.KineticenergyeVEditField = uieditfield(app.BeamoptionsPanel, 'numeric');
            app.KineticenergyeVEditField.Limits = [0 300000];
            app.KineticenergyeVEditField.ValueChangedFcn = createCallbackFcn(app, @KineticenergyeVEditFieldValueChanged, true);
            app.KineticenergyeVEditField.Value = 80000;
            app.WidthnmEditFieldLabel = uilabel(app.BeamoptionsPanel);
            app.WidthnmEditFieldLabel.Text = 'Width (nm)';
            app.WidthnmEditField = uieditfield(app.BeamoptionsPanel, 'numeric');
            app.WidthnmEditField.Limits = [0.1 5];
            app.WidthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthnmEditFieldValueChanged, true);
            app.WidthnmEditField.Value = 1;
            app.BeamrangeforXdirectionFarfieldscannmPanel = uipanel(app.BeamoptionsPanel);
            app.BeamrangeforXdirectionFarfieldscannmPanel.Title = 'Beam range for X direction Farfield scan (nm)';
            app.MaxXEditFieldLabel = uilabel(app.BeamrangeforXdirectionFarfieldscannmPanel);
            app.MaxXEditFieldLabel.Text = 'Max X';
            app.MaxXEditField = uieditfield(app.BeamrangeforXdirectionFarfieldscannmPanel, 'numeric');
            app.MaxXEditField.Limits = [-1000 1000];
            app.MaxXEditField.ValueChangedFcn = createCallbackFcn(app, @MaxXEditFieldValueChanged, true);
            app.MaxXEditField.Value = 110;
            app.StepsXEditFieldLabel = uilabel(app.BeamrangeforXdirectionFarfieldscannmPanel);
            app.StepsXEditFieldLabel.Text = 'Steps X';
            app.StepsXEditField = uieditfield(app.BeamrangeforXdirectionFarfieldscannmPanel, 'numeric');
            app.StepsXEditField.Limits = [1 200];
            app.StepsXEditField.ValueChangedFcn = createCallbackFcn(app, @StepsXEditFieldValueChanged, true);
            app.StepsXEditField.Value = 50;
            app.MinXEditFieldLabel = uilabel(app.BeamrangeforXdirectionFarfieldscannmPanel);
            app.MinXEditFieldLabel.Text = 'Min X';
            app.MinXEditField = uieditfield(app.BeamrangeforXdirectionFarfieldscannmPanel, 'numeric');
            app.MinXEditField.Limits = [-1000 1000];
            app.MinXEditField.ValueChangedFcn = createCallbackFcn(app, @MinXEditFieldValueChanged, true);
            app.TextArea_5 = uitextarea(app.BeamrangeforXdirectionFarfieldscannmPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Only used when steps > 1'};
            app.BeampositionnmPanel = uipanel(app.BeamoptionsPanel);
            app.BeampositionnmPanel.Title = 'Beam position  (nm)';
            app.PositionXEditFieldLabel = uilabel(app.BeampositionnmPanel);
            app.PositionXEditFieldLabel.Text = 'Position X';
            app.PositionXEditField = uieditfield(app.BeampositionnmPanel, 'numeric');
            app.PositionXEditField.Limits = [-1000 1000];
            app.PositionXEditField.ValueChangedFcn = createCallbackFcn(app, @PositionXEditFieldValueChanged, true);
            app.PositionXEditField.Value = 110;
            app.YEditFieldLabel = uilabel(app.BeampositionnmPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.BeampositionnmPanel, 'numeric');
            app.YEditField.Limits = [-1000 1000];
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ResolutionformapsPanel = uipanel(app.BeamoptionsPanel);
            app.ResolutionformapsPanel.Title = 'Resolution for maps';
            app.StepsXYEditFieldLabel = uilabel(app.ResolutionformapsPanel);
            app.StepsXYEditFieldLabel.Text = 'Steps XY';
            app.StepsXYEditField = uieditfield(app.ResolutionformapsPanel, 'numeric');
            app.StepsXYEditField.Limits = [1 10000];
            app.StepsXYEditField.ValueChangedFcn = createCallbackFcn(app, @StepsXYEditFieldValueChanged, true);
            app.StepsXYEditField.Value = 50;
            app.PWTab_2 = uitab(app.TabGroup);
            app.PWTab_2.Title = 'PW';
            app.LightpolarizationPanel = uipanel(app.PWTab_2);
            app.LightpolarizationPanel.Title = 'Light polarization';
            app.PolarizationDropDownLabel = uilabel(app.LightpolarizationPanel);
            app.PolarizationDropDownLabel.Text = 'Polarization';
            app.PolarizationDropDown = uidropdown(app.LightpolarizationPanel);
            app.PolarizationDropDown.Items = {'p', 's', 'Pol vector', 'Pol Angle'};
            app.PolarizationDropDown.ItemsData = {'1', '2', '3', '4'};
            app.PolarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarizationDropDownValueChanged, true);
            app.PolarizationDropDown.Value = '1';
            app.PolarizationangledegEditFieldLabel = uilabel(app.LightpolarizationPanel);
            app.PolarizationangledegEditFieldLabel.Text = 'Polarization angle (deg)';
            app.PolarizationangledegEditField = uieditfield(app.LightpolarizationPanel, 'numeric');
            app.PolarizationangledegEditField.Limits = [-360 360];
            app.PolarizationangledegEditField.ValueChangedFcn = createCallbackFcn(app, @PolarizationangledegEditFieldValueChanged, true);
            app.PolarizationangledegEditField.Value = 15;
            app.JonesvectorsPanel = uipanel(app.LightpolarizationPanel);
            app.JonesvectorsPanel.Title = 'Jones vectors';
            app.Dir_xEditFieldLabel = uilabel(app.JonesvectorsPanel);
            app.Dir_xEditFieldLabel.Text = 'Dir_x';
            app.Dir_xEditField = uieditfield(app.JonesvectorsPanel, 'numeric');
            app.Dir_xEditField.ValueChangedFcn = createCallbackFcn(app, @Dir_xEditFieldValueChanged, true);
            app.Dir_yEditFieldLabel = uilabel(app.JonesvectorsPanel);
            app.Dir_yEditFieldLabel.Text = 'Dir_y';
            app.Dir_yEditField = uieditfield(app.JonesvectorsPanel, 'numeric');
            app.Dir_yEditField.ValueChangedFcn = createCallbackFcn(app, @Dir_yEditFieldValueChanged, true);
            app.Dir_zEditFieldLabel = uilabel(app.JonesvectorsPanel);
            app.Dir_zEditFieldLabel.Text = 'Dir_z';
            app.Dir_zEditField = uieditfield(app.JonesvectorsPanel, 'numeric');
            app.Dir_zEditField.ValueChangedFcn = createCallbackFcn(app, @Dir_zEditFieldValueChanged, true);
            app.Dir_zEditField.Value = 1;
            app.DirectionLabel = uilabel(app.JonesvectorsPanel);
            app.DirectionLabel.Text = 'Direction';
            app.ExEditFieldLabel = uilabel(app.JonesvectorsPanel);
            app.ExEditFieldLabel.Text = 'Ex';
            app.ExEditField = uieditfield(app.JonesvectorsPanel, 'text');
            app.ExEditField.ValueChangedFcn = createCallbackFcn(app, @ExEditFieldValueChanged, true);
            app.ExEditField.Value = '1';
            app.EyEditFieldLabel = uilabel(app.JonesvectorsPanel);
            app.EyEditFieldLabel.Text = 'Ey';
            app.EyEditField = uieditfield(app.JonesvectorsPanel, 'text');
            app.EyEditField.ValueChangedFcn = createCallbackFcn(app, @EyEditFieldValueChanged, true);
            app.EyEditField.Value = '0';
            app.EzEditFieldLabel = uilabel(app.JonesvectorsPanel);
            app.EzEditFieldLabel.Text = 'Ez';
            app.EzEditField = uieditfield(app.JonesvectorsPanel, 'text');
            app.EzEditField.ValueChangedFcn = createCallbackFcn(app, @EzEditFieldValueChanged, true);
            app.EzEditField.Value = '0';
            app.DipoleTab = uitab(app.TabGroup);
            app.DipoleTab.Title = 'Dipole';
            app.DipolesettingsPanel = uipanel(app.DipoleTab);
            app.DipolesettingsPanel.Title = 'Dipole settings';
            app.OscillationdirectionDropDownLabel = uilabel(app.DipolesettingsPanel);
            app.OscillationdirectionDropDownLabel.Text = 'Oscillation direction';
            app.OscillationdirectionDropDown = uidropdown(app.DipolesettingsPanel);
            app.OscillationdirectionDropDown.Items = {'x', 'y', 'z'};
            app.OscillationdirectionDropDown.ItemsData = {'1', '2', '3'};
            app.OscillationdirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @OscillationdirectionDropDownValueChanged, true);
            app.OscillationdirectionDropDown.Value = '1';
            app.DipolepositionnmPanel = uipanel(app.DipolesettingsPanel);
            app.DipolepositionnmPanel.Title = 'Dipole position (nm)';
            app.XEditFieldLabel = uilabel(app.DipolepositionnmPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.DipolepositionnmPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField_2Label = uilabel(app.DipolepositionnmPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.DipolepositionnmPanel, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.ZEditField_2Label = uilabel(app.DipolepositionnmPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.DipolepositionnmPanel, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.TextArea = uitextarea(app.DipolepositionnmPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Range for spatial scans in nm is defined from the Beam tab'};
            app.ReplacedipolewithQDCheckBox = uicheckbox(app.DipolesettingsPanel);
            app.ReplacedipolewithQDCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReplacedipolewithQDCheckBoxValueChanged, true);
            app.ReplacedipolewithQDCheckBox.Text = 'Replace dipole with QD';
            app.TextArea_4 = uitextarea(app.DipolesettingsPanel);
            app.TextArea_4.Value = {'ε of QD defined by'; 'ε(3). Disable for mapping.'};
            app.CancelButton = uibutton(app.CLmap, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.CLmap, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.MNPBEMGUIV23Label = uilabel(app.CLmap);
            app.MNPBEMGUIV23Label.Text = 'MNPBEM GUI V2.3';
            app.StructurePanel = uipanel(app.CLmap);
            app.StructurePanel.Title = 'Structure';
            app.UIAxes2 = uiaxes(app.StructurePanel);
            app.DviewButton = uibutton(app.StructurePanel, 'push');
            app.DviewButton.ButtonPushedFcn = createCallbackFcn(app, @DviewButtonPushed, true);
            app.DviewButton.Text = '3D view';
            app.DetectorButton = uibutton(app.StructurePanel, 'push');
            app.DetectorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorButtonPushed, true);
            app.DetectorButton.Text = 'Detector';
            app.RefractiveIndexPanel = uipanel(app.CLmap);
            app.RefractiveIndexPanel.Title = 'Refractive Index';
            app.TabGroup3 = uitabgroup(app.RefractiveIndexPanel);
            app.nk1Tab = uitab(app.TabGroup3);
            app.nk1Tab.Title = 'n, k  (1)';
            app.UIAxes = uiaxes(app.nk1Tab);
            xlabel(app.UIAxes, 'eV')
            ylabel(app.UIAxes, 'n, k')
            app.nk2Tab = uitab(app.TabGroup3);
            app.nk2Tab.Title = 'n, k (2)';
            app.UIAxes_2 = uiaxes(app.nk2Tab);
            xlabel(app.UIAxes_2, 'eV')
            ylabel(app.UIAxes_2, 'n, k')
            app.nk3Tab = uitab(app.TabGroup3);
            app.nk3Tab.Title = 'n, k (3)';
            app.UIAxes_3 = uiaxes(app.nk3Tab);
            xlabel(app.UIAxes_3, 'eV')
            ylabel(app.UIAxes_3, 'n, k')
            app.ExcitationsourcePanel = uipanel(app.CLmap);
            app.ExcitationsourcePanel.Title = 'Excitation source';
            app.SourceDropDownLabel = uilabel(app.ExcitationsourcePanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.ExcitationsourcePanel);
            app.SourceDropDown.Items = {'E-beam', 'Plane wave', 'Dipole'};
            app.SourceDropDown.ItemsData = {'1', '2', '3'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = '2';
            app.TextArea_3 = uitextarea(app.CLmap);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'*Reference as described by the official MNPBEM website.'};
            app.GUIauthorNMatthaiakakisLabel = uilabel(app.CLmap);
            app.GUIauthorNMatthaiakakisLabel.Text = 'GUI author: N. Matthaiakakis';
            % Show the figure after all components are created
            app.CLmap.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MNPBEM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLmap)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLmap)
        end
    end
end